classdef App_1_VWBA_Tools < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewProjectMenu                  matlab.ui.container.Menu
        OpenProjectMenu                 matlab.ui.container.Menu
        EditProjectMenu                 matlab.ui.container.Menu
        BasinMenu                       matlab.ui.container.Menu
        CreatebasinMenu                 matlab.ui.container.Menu
        EditBasinMenu                   matlab.ui.container.Menu
        ReportMenu                      matlab.ui.container.Menu
        CreatereportMenu                matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        DataBaseMenu                    matlab.ui.container.Menu
        Panel_Index                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        AgriculturalpracticesTab        matlab.ui.container.Tab
        Area_AgriPrac                   matlab.ui.control.NumericEditField
        AreahaEditFieldLabel            matlab.ui.control.Label
        Lamp_AgriPrac                   matlab.ui.control.Lamp
        Button_Porfolio                 matlab.ui.control.Button
        Panel_AgriPrac                  matlab.ui.container.Panel
        Panel_Index_AP                  matlab.ui.container.TabGroup
        AP1                             matlab.ui.container.Tab
        Params_AP1_CN_H                 matlab.ui.control.NumericEditField
        Params_AP1_CN_F                 matlab.ui.control.NumericEditField
        Params_AP1_TS_Path              matlab.ui.control.EditField
        GDB_AP1_CN_H                    matlab.ui.control.Button
        GDB_AP1_CN_F                    matlab.ui.control.Button
        GDB_AP1_TS_P                    matlab.ui.control.Button
        Load_AP1_TS_P                   matlab.ui.control.Button
        Ouputs_AP1                      matlab.ui.container.Panel
        Index_AP1                       matlab.ui.control.NumericEditField
        Reducedrunoffm3Label            matlab.ui.control.Label
        Processing_AP1                  matlab.ui.control.Button
        TimeseriesdailyprecipitationcsvLabel  matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        CurvenumberWithActivitiesLabel  matlab.ui.control.Label
        CurvenumberRasterLabel          matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        AP2                             matlab.ui.container.Tab
        Params_AP2_CO2e_B               matlab.ui.control.NumericEditField
        Params_AP2_CO2e_P               matlab.ui.control.NumericEditField
        Params_AP2_PAW                  matlab.ui.control.NumericEditField
        Params_AP2_RootDepth            matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_5       matlab.ui.control.Label
        CO2eWithActivitiesLabel_4       matlab.ui.control.Label
        CO2eWithActivitiesLabel_2       matlab.ui.control.Label
        CO2eWithActivitiesLabel         matlab.ui.control.Label
        Params_AP2_NTR                  matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_13      matlab.ui.control.Label
        GDB_AP_2_CO2e_B                 matlab.ui.control.Button
        GDB_AP_2_CO2e_P                 matlab.ui.control.Button
        GDB_AP_2_PWA                    matlab.ui.control.Button
        GDB_AP_2_RootDepth              matlab.ui.control.Button
        Ouputs_AP2                      matlab.ui.container.Panel
        Index_AP2                       matlab.ui.control.NumericEditField
        VolumenrunoffLabel              matlab.ui.control.Label
        Processing_AP2                  matlab.ui.control.Button
        Hyperlink_6                     matlab.ui.control.Hyperlink
        Label_21                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        AP3                             matlab.ui.container.Tab
        CO2eWithActivitiesLabel_12      matlab.ui.control.Label
        CO2eWithActivitiesLabel_11      matlab.ui.control.Label
        CO2eWithActivitiesLabel_10      matlab.ui.control.Label
        CO2eWithActivitiesLabel_9       matlab.ui.control.Label
        Param_AP3_WAR                   matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_8       matlab.ui.control.Label
        Param_AP3_IIA                   matlab.ui.control.NumericEditField
        Param_AP3_TIN                   matlab.ui.control.NumericEditField
        Param_AP3_NA                    matlab.ui.control.NumericEditField
        Param_AP3_NL                    matlab.ui.control.NumericEditField
        GDB_AP3_VI                      matlab.ui.control.Button
        Ouputs_AP3                      matlab.ui.container.Panel
        Index_AP3                       matlab.ui.control.NumericEditField
        VolumenrunoffLabel_2            matlab.ui.control.Label
        Processing_AP3                  matlab.ui.control.Button
        Hyperlink_7                     matlab.ui.control.Hyperlink
        Label_23                        matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        DemandmanagementTab             matlab.ui.container.Tab
        Label_55                        matlab.ui.control.Label
        Panel_DeManag                   matlab.ui.container.Panel
        TabGroup2_3                     matlab.ui.container.TabGroup
        VWBlegaltransactionsTab         matlab.ui.container.Tab
        WCN1_Param_1                    matlab.ui.control.NumericEditField
        WCN1_Param_2                    matlab.ui.control.NumericEditField
        CurvenumberWithActivitiesLabel_7  matlab.ui.control.Label
        CurvenumberRasterLabel_6        matlab.ui.control.Label
        Button_WCN1                     matlab.ui.control.Button
        OuputsPanel_13                  matlab.ui.container.Panel
        Index_WCN1                      matlab.ui.control.NumericEditField
        Reducedrunoffm3Label_6          matlab.ui.control.Label
        Hyperlink_WCN1                  matlab.ui.control.Hyperlink
        Label_99                        matlab.ui.control.Label
        Label_98                        matlab.ui.control.Label
        Label_97                        matlab.ui.control.Label
        Label_96                        matlab.ui.control.Label
        Label_95                        matlab.ui.control.Label
        Label_94                        matlab.ui.control.Label
        VWBlegaltransactionsandAgriculturalirrigationTab  matlab.ui.container.Tab
        WCN2_Param_1                    matlab.ui.control.NumericEditField
        WCN2_Param_2                    matlab.ui.control.NumericEditField
        CurvenumberRasterLabel_7        matlab.ui.control.Label
        WCN2_Param_3                    matlab.ui.control.NumericEditField
        OuputsPanel_14                  matlab.ui.container.Panel
        Button_WCN2                     matlab.ui.control.Button
        Index_WCN2                      matlab.ui.control.NumericEditField
        Reducedrunoffm3Label_7          matlab.ui.control.Label
        Hyperlink_WCN2                  matlab.ui.control.Hyperlink
        Label_42                        matlab.ui.control.Label
        Label_41                        matlab.ui.control.Label
        Label_40                        matlab.ui.control.Label
        Label_39                        matlab.ui.control.Label
        CurvenumberWithActivitiesLabel_3  matlab.ui.control.Label
        CurvenumberRasterLabel_3        matlab.ui.control.Label
        Label_38                        matlab.ui.control.Label
        Label_37                        matlab.ui.control.Label
        VWBNonrevenueWaterTab           matlab.ui.container.Tab
        CurvenumberWithActivitiesLabel_13  matlab.ui.control.Label
        CurvenumberRasterLabel_11       matlab.ui.control.Label
        WCN3_Param_1                    matlab.ui.control.NumericEditField
        CurvenumberRasterLabel_10       matlab.ui.control.Label
        WCN3_Param_2                    matlab.ui.control.NumericEditField
        WCN3_Param_3                    matlab.ui.control.NumericEditField
        OuputsPanel_16                  matlab.ui.container.Panel
        Button_WCN3                     matlab.ui.control.Button
        Index_WCN3                      matlab.ui.control.NumericEditField
        Reducedrunoffm3Label_9          matlab.ui.control.Label
        Hyperlink_WCN3                  matlab.ui.control.Hyperlink
        Label_111                       matlab.ui.control.Label
        Label_110                       matlab.ui.control.Label
        Label_109                       matlab.ui.control.Label
        Label_108                       matlab.ui.control.Label
        Label_107                       matlab.ui.control.Label
        Label_106                       matlab.ui.control.Label
        VWBAgriculturalirrigationefficiencyTab  matlab.ui.container.Tab
        WCN4_Param_1                    matlab.ui.control.NumericEditField
        WCN4_Param_2                    matlab.ui.control.NumericEditField
        CurvenumberRasterLabel_9        matlab.ui.control.Label
        CurvenumberWithActivitiesLabel_9  matlab.ui.control.Label
        WCN4_Param_3                    matlab.ui.control.NumericEditField
        CurvenumberWithActivitiesLabel_8  matlab.ui.control.Label
        WCN4_Param_4                    matlab.ui.control.NumericEditField
        CurvenumberRasterLabel_8        matlab.ui.control.Label
        OuputsPanel_15                  matlab.ui.container.Panel
        Button_WCN4                     matlab.ui.control.Button
        Index_WCN4                      matlab.ui.control.NumericEditField
        Reducedrunoffm3Label_8          matlab.ui.control.Label
        Hyperlink_WCN4                  matlab.ui.control.Hyperlink
        Label_105                       matlab.ui.control.Label
        Label_104                       matlab.ui.control.Label
        Label_103                       matlab.ui.control.Label
        Label_102                       matlab.ui.control.Label
        Label_101                       matlab.ui.control.Label
        Label_100                       matlab.ui.control.Label
        VWBLandcoverrestorationTab      matlab.ui.container.Tab
        CurvenumberRasterLabel_12       matlab.ui.control.Label
        WCN5_GDB_4                      matlab.ui.control.Button
        WCN5_GDB_3                      matlab.ui.control.Button
        CurvenumberWithActivitiesLabel_11  matlab.ui.control.Label
        WCN5_Param_1                    matlab.ui.control.NumericEditField
        CurvenumberRasterLabel_13       matlab.ui.control.Label
        WCN5_Param_2                    matlab.ui.control.NumericEditField
        CurvenumberWithActivitiesLabel_10  matlab.ui.control.Label
        WCN5_Param_3                    matlab.ui.control.NumericEditField
        WCN5_Param_4                    matlab.ui.control.NumericEditField
        OuputsPanel_17                  matlab.ui.container.Panel
        Button_WCN5                     matlab.ui.control.Button
        Index_WCN5                      matlab.ui.control.NumericEditField
        Reducedrunoffm3Label_10         matlab.ui.control.Label
        Hyperlink_11                    matlab.ui.control.Hyperlink
        Label_117                       matlab.ui.control.Label
        Label_116                       matlab.ui.control.Label
        Label_115                       matlab.ui.control.Label
        Label_114                       matlab.ui.control.Label
        Label_113                       matlab.ui.control.Label
        Label_112                       matlab.ui.control.Label
        GreenandgrayinfrastructureTab   matlab.ui.container.Tab
        Label_74                        matlab.ui.control.Label
        Panel_GreenInfra                matlab.ui.container.Panel
        TabGroup2_4                     matlab.ui.container.TabGroup
        VolumecapturedTab_3             matlab.ui.container.Tab
        GGI1_Param_1                    matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_53      matlab.ui.control.Label
        CO2eWithActivitiesLabel_52      matlab.ui.control.Label
        GGI1_Param_2                    matlab.ui.control.NumericEditField
        GGI1_Param_3                    matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_54      matlab.ui.control.Label
        GGI1_Param_4                    matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_55      matlab.ui.control.Label
        Hyperlink_17                    matlab.ui.control.Hyperlink
        OuputsPanel_22                  matlab.ui.container.Panel
        Index_GGI1                      matlab.ui.control.NumericEditField
        VolumenrunoffLabel_11           matlab.ui.control.Label
        Button_GGI1                     matlab.ui.control.Button
        Button_GDB_GG1                  matlab.ui.control.Button
        Label_147                       matlab.ui.control.Label
        Label_146                       matlab.ui.control.Label
        Label_145                       matlab.ui.control.Label
        Label_144                       matlab.ui.control.Label
        Label_143                       matlab.ui.control.Label
        Label_142                       matlab.ui.control.Label
        VolumetreatedTab_2              matlab.ui.container.Tab
        ChallengesSpinner               matlab.ui.control.Spinner
        ChallengesSpinnerLabel          matlab.ui.control.Label
        GGI2_Table                      matlab.ui.control.Table
        GGI2_Param_1                    matlab.ui.control.NumericEditField
        CurvenumberRasterLabel_15       matlab.ui.control.Label
        Hyperlink_12                    matlab.ui.control.Hyperlink
        OuputsPanel_18                  matlab.ui.container.Panel
        Index_GGI2                      matlab.ui.control.NumericEditField
        Reducedrunoffm3Label_11         matlab.ui.control.Label
        Button_Processing_Ax1_11        matlab.ui.control.Button
        Label_123                       matlab.ui.control.Label
        Label_122                       matlab.ui.control.Label
        Label_121                       matlab.ui.control.Label
        Label_120                       matlab.ui.control.Label
        Label_119                       matlab.ui.control.Label
        Label_118                       matlab.ui.control.Label
        VolumeprovidedTab_2             matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        VolumeProvidedMethodTab         matlab.ui.container.Tab
        GGI3_Param_1                    matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_32      matlab.ui.control.Label
        CO2eWithActivitiesLabel_31      matlab.ui.control.Label
        GGI3_Param_2                    matlab.ui.control.NumericEditField
        GGI3_Param_3                    matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_45      matlab.ui.control.Label
        Hyperlink_13                    matlab.ui.control.Hyperlink
        OuputsPanel_9                   matlab.ui.container.Panel
        Index_GGI3                      matlab.ui.control.NumericEditField
        VolumenrunoffLabel_5            matlab.ui.control.Label
        Button_GGI3                     matlab.ui.control.Button
        Button_GDB_RPerCapita           matlab.ui.control.Button
        Label_73                        matlab.ui.control.Label
        Label_72                        matlab.ui.control.Label
        Label_71                        matlab.ui.control.Label
        Label_70                        matlab.ui.control.Label
        Label_69                        matlab.ui.control.Label
        Label_68                        matlab.ui.control.Label
        HabitatvolumemethodTab          matlab.ui.container.Tab
        GGI4_Param_1                    matlab.ui.control.NumericEditField
        GGI4_Param_2                    matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_60      matlab.ui.control.Label
        GGI4_Param_3                    matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_59      matlab.ui.control.Label
        CO2eWithActivitiesLabel_58      matlab.ui.control.Label
        Hyperlink_18                    matlab.ui.control.Hyperlink
        OuputsPanel_23                  matlab.ui.container.Panel
        Index_GGI4                      matlab.ui.control.NumericEditField
        VolumenrunoffLabel_12           matlab.ui.control.Label
        Button_GGI4                     matlab.ui.control.Button
        Label_153                       matlab.ui.control.Label
        Label_152                       matlab.ui.control.Label
        Label_151                       matlab.ui.control.Label
        Label_150                       matlab.ui.control.Label
        Label_149                       matlab.ui.control.Label
        Label_148                       matlab.ui.control.Label
        IncreasedormaintainedinundationTab  matlab.ui.container.Tab
        CO2eWithActivitiesLabel_56      matlab.ui.control.Label
        CO2eWithActivitiesLabel_51      matlab.ui.control.Label
        GGI5_Param_1                    matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_50      matlab.ui.control.Label
        CO2eWithActivitiesLabel_49      matlab.ui.control.Label
        GGI5_Param_2                    matlab.ui.control.NumericEditField
        GGI5_Param_3                    matlab.ui.control.NumericEditField
        GGI5_Param_4                    matlab.ui.control.NumericEditField
        Hyperlink_16                    matlab.ui.control.Hyperlink
        OuputsPanel_21                  matlab.ui.container.Panel
        Index_GGI5                      matlab.ui.control.NumericEditField
        VolumenrunoffLabel_10           matlab.ui.control.Label
        Button_GGI5                     matlab.ui.control.Button
        Label_141                       matlab.ui.control.Label
        Label_140                       matlab.ui.control.Label
        Label_139                       matlab.ui.control.Label
        Label_138                       matlab.ui.control.Label
        Label_137                       matlab.ui.control.Label
        Label_136                       matlab.ui.control.Label
        IncreasedrechargeTab            matlab.ui.container.Tab
        Button_GDB_GG7_1                matlab.ui.control.Button
        Button_GDB_GG7_2                matlab.ui.control.Button
        CO2eWithActivitiesLabel_68      matlab.ui.control.Label
        GGI7_Param_1                    matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_67      matlab.ui.control.Label
        CO2eWithActivitiesLabel_66      matlab.ui.control.Label
        GGI7_Param_2                    matlab.ui.control.NumericEditField
        GGI7_Param_3                    matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_65      matlab.ui.control.Label
        CO2eWithActivitiesLabel_64      matlab.ui.control.Label
        CO2eWithActivitiesLabel_63      matlab.ui.control.Label
        CO2eWithActivitiesLabel_61      matlab.ui.control.Label
        GGI7_Param_4                    matlab.ui.control.NumericEditField
        GGI7_Param_5                    matlab.ui.control.NumericEditField
        GGI7_Param_6                    matlab.ui.control.NumericEditField
        GGI7_Param_7                    matlab.ui.control.NumericEditField
        Hyperlink_19                    matlab.ui.control.Hyperlink
        OuputsPanel_24                  matlab.ui.container.Panel
        Index_GGI7                      matlab.ui.control.NumericEditField
        VolumenrunoffLabel_13           matlab.ui.control.Label
        Button_GGI7                     matlab.ui.control.Button
        Label_159                       matlab.ui.control.Label
        Label_158                       matlab.ui.control.Label
        Label_157                       matlab.ui.control.Label
        Label_156                       matlab.ui.control.Label
        Label_155                       matlab.ui.control.Label
        Label_154                       matlab.ui.control.Label
        VolumeimprovedTab               matlab.ui.container.Tab
        CO2eWithActivitiesLabel_73      matlab.ui.control.Label
        CO2eWithActivitiesLabel_72      matlab.ui.control.Label
        CO2eWithActivitiesLabel_71      matlab.ui.control.Label
        CO2eWithActivitiesLabel_70      matlab.ui.control.Label
        GGI6_Param_1                    matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_69      matlab.ui.control.Label
        GGI6_Param_2                    matlab.ui.control.NumericEditField
        GGI6_Param_3                    matlab.ui.control.NumericEditField
        GGI6_Param_4                    matlab.ui.control.NumericEditField
        GGI6_Param_5                    matlab.ui.control.NumericEditField
        Hyperlink_21                    matlab.ui.control.Hyperlink
        OuputsPanel_25                  matlab.ui.container.Panel
        Index_GGI6                      matlab.ui.control.NumericEditField
        VolumenrunoffLabel_14           matlab.ui.control.Label
        Button_Processing_GGI6          matlab.ui.control.Button
        Button_GDB_GG6_1                matlab.ui.control.Button
        Label_165                       matlab.ui.control.Label
        Label_164                       matlab.ui.control.Label
        Label_163                       matlab.ui.control.Label
        Label_162                       matlab.ui.control.Label
        Label_161                       matlab.ui.control.Label
        Label_160                       matlab.ui.control.Label
        LandconservationandrestorationTab  matlab.ui.container.Tab
        PercentageArea_Con              matlab.ui.control.NumericEditField
        ConservationLabel               matlab.ui.control.Label
        Area_CR                         matlab.ui.control.NumericEditField
        AreahaEditFieldLabel_2          matlab.ui.control.Label
        Lamp_CR                         matlab.ui.control.Lamp
        Button_Porfolio_CR              matlab.ui.control.Button
        Label_36                        matlab.ui.control.Label
        Panel_CR                        matlab.ui.container.Panel
        TabGroup2_2                     matlab.ui.container.TabGroup
        AvoidedrunoffTab                matlab.ui.container.Tab
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Button_GDB_CN_H_2               matlab.ui.control.Button
        Button_GDB_CN_F_2               matlab.ui.control.Button
        Button_GDB_TS_14                matlab.ui.control.Button
        Button_Load_TS_2                matlab.ui.control.Button
        OuputsPanel_4                   matlab.ui.container.Panel
        Index_CR                        matlab.ui.control.NumericEditField
        Reducedrunoffm3Label_2          matlab.ui.control.Label
        Button_CR                       matlab.ui.control.Button
        Edit_TS_Path_2                  matlab.ui.control.EditField
        TimeseriesdailyprecipitationcsvLabel_2  matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        CN_F_2                          matlab.ui.control.NumericEditField
        CurvenumberWithActivitiesLabel_2  matlab.ui.control.Label
        CN_H_2                          matlab.ui.control.NumericEditField
        CurvenumberRasterLabel_2        matlab.ui.control.Label
        Label_25                        matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        MaintainedrechargeTab           matlab.ui.container.Tab
        Hyperlink_20                    matlab.ui.control.Hyperlink
        PWP_CR                          matlab.ui.control.NumericEditField
        CurvenumberRasterLabel_21       matlab.ui.control.Label
        Button_GDB_PWA_CR               matlab.ui.control.Button
        Rr_CR                           matlab.ui.control.NumericEditField
        CurvenumberRasterLabel_20       matlab.ui.control.Label
        Button_GDB_Rr_CR                matlab.ui.control.Button
        TimeseriesdailyprecipitationcsvLabel_8  matlab.ui.control.Label
        Button_GDB_CS_CR                matlab.ui.control.Button
        OuputsPanel_5                   matlab.ui.container.Panel
        Index_CR_R                      matlab.ui.control.NumericEditField
        Index_CR_Sw                     matlab.ui.control.NumericEditField
        VolumenrunoffLabel_8            matlab.ui.control.Label
        Button_Processing_4             matlab.ui.control.Button
        VolumenrunoffLabel_3            matlab.ui.control.Label
        Smax_CR                         matlab.ui.control.NumericEditField
        CurvenumberRasterLabel_19       matlab.ui.control.Label
        CC_CR                           matlab.ui.control.NumericEditField
        CurvenumberRasterLabel_18       matlab.ui.control.Label
        CN_H_CR                         matlab.ui.control.NumericEditField
        CurvenumberRasterLabel_16       matlab.ui.control.Label
        CN_F_CR                         matlab.ui.control.NumericEditField
        CurvenumberWithActivitiesLabel_14  matlab.ui.control.Label
        TS_Path_CS_CR                   matlab.ui.control.EditField
        TS_Path_ET_CR                   matlab.ui.control.EditField
        TS_Path_P_CR                    matlab.ui.control.EditField
        TimeseriesdailyprecipitationcsvLabel_6  matlab.ui.control.Label
        TimeseriesdailyprecipitationcsvLabel_7  matlab.ui.control.Label
        Button_GDB_Smax_CR              matlab.ui.control.Button
        Button_GDB_FC_CR                matlab.ui.control.Button
        Button_GDB_CN_H_CR              matlab.ui.control.Button
        Button_GDB_CN_P_CR              matlab.ui.control.Button
        Button_Load_TS_CS_CR            matlab.ui.control.Button
        Button_GDB_TS__ET_CR            matlab.ui.control.Button
        Button_GDB_TS_P_CR              matlab.ui.control.Button
        Button_Load_TS_ET_CR            matlab.ui.control.Button
        Button_Load_TS_P_CR             matlab.ui.control.Button
        Label_34                        matlab.ui.control.Label
        Label_33                        matlab.ui.control.Label
        Label_32                        matlab.ui.control.Label
        Label_31                        matlab.ui.control.Label
        Label_30                        matlab.ui.control.Label
        Label_35                        matlab.ui.control.Label
        WASHTab                         matlab.ui.container.Tab
        Label_93                        matlab.ui.control.Label
        Panel_WASH                      matlab.ui.container.Panel
        TabGroup2_5                     matlab.ui.container.TabGroup
        VolumeprovidedTab_3             matlab.ui.container.Tab
        WASH2_Param_1                   matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_47      matlab.ui.control.Label
        CO2eWithActivitiesLabel_46      matlab.ui.control.Label
        WASH2_Param_2                   matlab.ui.control.NumericEditField
        WASH2_Param_3                   matlab.ui.control.NumericEditField
        CO2eWithActivitiesLabel_48      matlab.ui.control.Label
        Hyperlink_14                    matlab.ui.control.Hyperlink
        OuputsPanel_19                  matlab.ui.container.Panel
        Index_WASH2                     matlab.ui.control.NumericEditField
        VolumenrunoffLabel_9            matlab.ui.control.Label
        Button_WASH2                    matlab.ui.control.Button
        Button_GDB_RPerCapita_2         matlab.ui.control.Button
        Label_129                       matlab.ui.control.Label
        Label_128                       matlab.ui.control.Label
        Label_127                       matlab.ui.control.Label
        Label_126                       matlab.ui.control.Label
        Label_125                       matlab.ui.control.Label
        Label_124                       matlab.ui.control.Label
        VolumetreatedTab_3              matlab.ui.container.Tab
        ChallengesSpinner_2             matlab.ui.control.Spinner
        ChallengesSpinner_2Label        matlab.ui.control.Label
        WASH_Param_1                    matlab.ui.control.NumericEditField
        CurvenumberRasterLabel_22       matlab.ui.control.Label
        WASH_Table                      matlab.ui.control.Table
        Hyperlink_15                    matlab.ui.control.Hyperlink
        OuputsPanel_20                  matlab.ui.container.Panel
        Index_WASH                      matlab.ui.control.NumericEditField
        Reducedrunoffm3Label_12         matlab.ui.control.Label
        Button_WASH                     matlab.ui.control.Button
        Label_135                       matlab.ui.control.Label
        Label_134                       matlab.ui.control.Label
        Label_133                       matlab.ui.control.Label
        Label_132                       matlab.ui.control.Label
        Label_131                       matlab.ui.control.Label
        Label_130                       matlab.ui.control.Label
        ProjectName                     matlab.ui.control.EditField
        ProjectNameEditFieldLabel       matlab.ui.control.Label
        VolumetricWaterBenefitAccountingLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = public)
        % Ruta de instalación de la App
        AppPath
        % Ruta de la base de datos
        DataBasePath
        % Ruta del project
        ProjectPath
        % UserData
        UserData
        % AppInfo
        AppInfo
        % Area de la cuencas (m2)
        BasinArea
        % Nombre la región
        NameRegion
        % Area de Bosque (m2)
        ForestArea
        % Longitud de ríos (km)
        LengthRivers
        % Área de humedales (m2)
        LakesArea
        % Áreas de importancia para la biodiversidad (m2)
        AreaBio
        % Número de personas
        People 
        % Status de procesamiento. Ojo, el 20 es por que hay 20 indicadores
        % si se incluyen más toca cambiar este número
        StatusProcess = false(20,1)
    end
    
    methods (Access = public)
        % -----------------------------------------------------------------
        % función para apagar los botones miestras estan abiertas otras 
        % ventanas
        function Disable_Project(app)
            % Deshabilitar File Menu
            app.FileMenu.Enable     = "off";
            % Deshabilitar Basin Menu
            app.BasinMenu.Enable    = "off";
            % Deshabilitar panel de indicadores
            app.Panel_Index.Enable  = "off";
            % Deshabilitar Report Menu
            app.ReportMenu.Enable   = "off"; 
        end
        
        function Enable_Project(app)
            % Habilitar File Menu
            app.FileMenu.Enable     = "on";
            % Habilitar Basin Menu
            app.BasinMenu.Enable    = "on";
            % Deshabilitar Report Menu
            app.ReportMenu.Enable   = "on"; 
            % Check si existe cuenca
            Check1 = isfile(fullfile(app.ProjectPath,'01-Basins','Basin.shp'));
            Check2 = isfile(fullfile(app.ProjectPath,'01-Basins','Point.shp'));

            % Habilita Edit Basin Menu y Index
            if (Check1&&Check2)
                app.EditBasinMenu.Enable    = "on";
                app.Panel_Index.Enable      = "on";
                % Habilitar Report Menu
                app.CreatereportMenu.Enable = "on";
            else
                app.EditBasinMenu.Enable    = "off";
                app.Panel_Index.Enable      = "off";
                % Habilitar Report Menu
                app.CreatereportMenu.Enable = "off";
            end        
        end
        
        function [ErrorStatus, P, Date] = Read_TS(app,TS_Path)
            ErrorStatus = false;         

            % Check archivo
            % leer archivo
            try
                Tmp = readmatrix(TS_Path);
            catch
                errordlg('There are not precipitation file')
                ErrorStatus = true;
            end

            % Verificar dimensión
            if size(Tmp,2) ~= 4
                errordlg('The format of the precipitation data is incorrect')
                ErrorStatus = true;
            end

            try
                % Configuracion de fecha
                Date  = datetime(Tmp(:,1),Tmp(:,2),Tmp(:,3));
            catch
                errordlg('The format of the precipitation data is incorrect')
                ErrorStatus = true;
            end
            
            % Precipitation
            P = Tmp(:,4);

            if sum(isnan(P)) > 0
                errordlg('There are Null values in the precipitation data. These values will be replaced by zero.')
                P(isnan(P)) = 0;
                ErrorStatus = false;
            end

            if sum(P<0) > 0
                errordlg('There are negative values in the precipitation data. These values will be replaced by zero.')
                P(P<0) = 0;
                ErrorStatus = false;
            end           
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Modo de pruebas o modo para compilar
            StatusTester = true;

            if StatusTester
                app.AppPath = fileparts(pwd);
            else
                % Leer la ruta donde está instalada la aplicación
                [~, result] = system('path');
                app.AppPath = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
                if isfile( fullfile(app.AppPath,'DATABASE.zip') )
                    % Progress bar
                    ProgressBar = waitbar(0, 'Configuring the database. Please do not close the window','Color',[1 1 1]);
                    wbch        = allchild(ProgressBar);
                    jp          = wbch(1).JavaPeer;
                    jp.setIndeterminate(1)
    
                    % Descomprimir Base de datos
                    unzip(fullfile(app.AppPath,'DATABASE.zip'))
                    delete(fullfile(app.AppPath,'DATABASE.zip'))
    
                    ZipName =  {'BD.zip',...
                                'Bio.zip',...
                                'CC.zip',...
                                'CN.zip',...
                                'Countries.zip',...
                                'DEM.zip',...
                                'ETP.zip',...
                                'FlowAcc.zip',...
                                'FlowDir.zip',...
                                'Ks.zip',...
                                'Lakes.zip',...
                                'LULC.zip',...  
                                'MSA.zip',...
                                'Other.zip',...
                                'P.zip',...
                                'People.zip',...
                                'PMP.zip',...
                                'Report.zip',...
                                'Rivers.zip',...
                                'SG.zip',...
                                'Smax.zip',...
                                'Soil_Depth.zip',...                                
                                'Stream.zip'};
    
                    for i = 1:length(ZipName)
                        % Descomprimir base de datos
                        PathZip = fullfile(app.AppPath,'DATABASE',ZipName{i});
                        unzip(PathZip,fullfile(app.AppPath,'DATABASE'));
    
                        % Eliminar archivo
                        delete(PathZip);
    
                    end
    
                    try
                        close(ProgressBar)
                        msgbox('Database successfully configured')
                    catch   
                        msgbox('Database successfully configured')
                    end
                end
            end                    

            % Ruta de la base de datos
            app.DataBasePath = fullfile(app.AppPath,'DATABASE');
  
            % Asignar valores de tabla
            app.GGI2_Table.ColumnFormat = {'short','short'};
            app.GGI2_Table.ColumnEditable = [false, true];
            app.GGI2_Table.Data = [1,0];

            % Asignar valores de tabla
            app.WASH_Table.ColumnFormat = {'short','short'};
            app.WASH_Table.ColumnEditable = [false, true];
            app.WASH_Table.Data = [1,0];

        end

        % Menu selected function: NewProjectMenu
        function NewProjectMenuSelected(app, event)
            % Leer ruta del proyecto
            Tmp = uigetdir('C:\','Select project path');
            
            % Asignar ruta de la carpeta
            if ~(Tmp == 0)
                app.ProjectPath = Tmp;
            else
                return
            end
            
            % Namae Folders
            NameFolder = {'01-Basins','02-Biophysic','03-Porfolio','04-Report'};
            for i = 1:length(NameFolder)
                % Borrar todos los elementos de la carpeta
                %delete(fullfile(app.ProjectPath,NameFolder{i},'*'))
    
                % Crear Directorios de datos
                mkdir(fullfile(app.ProjectPath,NameFolder{i}))
            end
            
            % Deshabilitar funcionalidades de App principal
            app.Disable_Project;

            % Asignar datos del proyecto
            app.AppInfo = App_2_InfoProject(app, []);
                   
           
        end

        % Menu selected function: OpenProjectMenu
        function OpenProjectMenuSelected(app, event)
            % Abrir explorador y leer proyecto en formato .mat
            [FileProject, PathFile] = uigetfile('C:\*.mat','Select project file');

            % Asignar ruta de la carpeta
            if ~(FileProject == 0)
                % Leer archivo del proyecto
                Tmp                     = matfile(fullfile(PathFile,FileProject));
                app.UserData            = Tmp.UserData;
                % Asignar ruta del proyecto
                app.ProjectPath         = PathFile;
                % Asignar nombre de proyecto
                app.ProjectName.Value   = app.UserData.ProjectName;
                % Check si existe cuenca
                Check1 = isfile(fullfile(app.ProjectPath,'01-Basins','Basin.shp'));
                Check2 = isfile(fullfile(app.ProjectPath,'01-Basins','Point.shp'));            
                
                % Habilita Edit Basin Menu y Index
                if (Check1&&Check2)
                    % Nombre de la Región
                    app.NameRegion      = app.UserData.NameRegion;
                    % Área de la cuenca
                    app.BasinArea       = app.UserData.BasinArea;
                    % Área de bosque
                    app.ForestArea      = app.UserData.ForestArea;
                    % Longitud de ríos
                    app.LengthRivers    = app.UserData.LengthRivers;
                    % Área de humedales
                    app.LakesArea       = app.UserData.LakesArea;
                    % Áreas de importancia para la biodiversidad
                    app.AreaBio         = app.UserData.AreaBio;
                    % People
                    app.People          = app.UserData.People;
                end
                
            else
                return
            end
            
            % Habilitar Edit Project Menu
            app.EditProjectMenu.Enable  = "on";
            % Habilitar Create Basin
            app.CreatebasinMenu.Enable  = "on";
            % Habilitar reporte
            app.CreatereportMenu.Enable = "on";
            % Habilitar funcionalidades de App principal
            app.Enable_Project;

        end

        % Menu selected function: EditProjectMenu
        function EditProjectMenuSelected(app, event)
            % Deshabilitar las funcionalidades de la app principal
            app.Disable_Project;

            % Asignar datos del proyecto
            App_2_InfoProject(app, app.UserData)
        end

        % Menu selected function: CreatebasinMenu
        function CreatebasinMenuSelected(app, event)
            % Deshabilitar las funcionalidades de la app principal
            app.Disable_Project;

            % False = Modo Creación; True = Modo edición
            StatusBasin = false;

            % Lanzar App de creación de cuencas
            App_3_CreateBasins(app, app.ProjectPath, app.ProjectName.Value, StatusBasin);

        end

        % Menu selected function: EditBasinMenu
        function EditBasinMenuSelected(app, event)
            % Check si existe cuenca
            Check1 = isfile(fullfile(app.ProjectPath,'01-Basins','Basin.shp'));
            Check2 = isfile(fullfile(app.ProjectPath,'01-Basins','Point.shp'));

            if ~Check1
                errordlg('The file "Basin.shp" was delete from the project')
                return
            end

            if ~Check2
                errordlg('The file "Points.shp" was delete from the project')
                return
            end
            
            % Deshabilitar las funcionalidades de la app principal
            app.Disable_Project;

            % False = Modo Creación; True = Modo edición
            StatusBasin = true;

            % Lanzar App de creación de cuencas
            App_3_CreateBasins(app, app.ProjectPath, app.ProjectName.Value, StatusBasin);

        end

        % Button pushed function: Button_Porfolio
        function Button_PorfolioPushed(app, event)
            % Cambiar estado
            app.Lamp_AgriPrac.Color = [0.9 0.9 0.9];
            
            % Leer valor de área para portafolio en ha
            Ap = app.Area_AgriPrac.Value;

            % Check que el área no se cero
            if Ap == 0
                errordlg('The value of the area must be greater than zero "0"')
                return
            end
                
            % Crear portafolio
            Porfolio_AgriPrac(app,Ap);

            % Cambiar estado
            app.Lamp_AgriPrac.Color = [0 1 0];

            % Habilitar panel de indicador
            app.Panel_AgriPrac.Enable = "on";
            
        end

        % Button pushed function: GDB_AP1_CN_H
        function GDB_AP1_CN_HPushed(app, event)
            % Estimación de Número de Curva para condición BaU con la
            % información de base de datos
            app.Params_AP1_CN_H.Value = CN_Without_AgriPrac(app);
        end

        % Button pushed function: GDB_AP1_CN_F
        function GDB_AP1_CN_FPushed(app, event)
            % Ruta del portafolio
            Path_Porfolio   = fullfile(app.ProjectPath,'03-Porfolio','Portfolio_AgriPrac.tif');

            % Estimación de Número de Curva para condición con SbN con las
            % información de base de datos
            app.Params_AP1_CN_F.Value = CN_With_AgriPrac(app,Path_Porfolio);
        end

        % Button pushed function: GDB_AP1_TS_P
        function GDB_AP1_TS_PPushed(app, event)
            % Asignar ruta de serie de tiempo de precipitación de base de
            % datos
            app.Params_AP1_TS_Path.Value = fullfile(app.ProjectPath,'02-Biophysic','P.csv');
        end

        % Button pushed function: Load_AP1_TS_P
        function Load_AP1_TS_PPushed(app, event)
            % Leer Serie de precipitación
            % Abrir explorador y leer proyecto en formato .mat
            [FileProject, PathFile] = uigetfile('C:\*.csv','Select precipitation file');

            % Asignar ruta de la carpeta
            if ~(FileProject == 0)
                % Asignar ruta al campo
                app.Params_AP1_TS_Path.Value = fullfile(PathFile, FileProject);
            end
        end

        % Button pushed function: Processing_AP1
        function Processing_AP1Pushed(app, event)
            % Leer datos de números de curva para condición BaU
            CNH = app.Params_AP1_CN_H.Value;

            % Leer datos de números de curva para condición con SbN
            CNF = app.Params_AP1_CN_F.Value;
            
            % Leer datos de precipitación
            TS_Path = app.Params_AP1_TS_Path.Value;

            % Leer serie de tiempo
            [ErrorStatus, P, Date] = app.Read_TS(TS_Path);            
            if ErrorStatus
                return
            end

            % Ejecutar Metodo Anexo 1 - Condición BaU
            QH = (Method_A1(P, CNH)/1000)*app.BasinArea;
            
            % Ejecutar Metodo Anexo 1 - Condición SbN
            QF = (Method_A1(P, CNF)/1000)*app.BasinArea;
            
            % Estimar Indicador VWB (promedio anual)
            VWBA = mean(sum(QH.*(year(Date)==unique(year(Date))'))) - ...
                   mean(sum(QF.*(year(Date)==unique(year(Date))')));

            % Mostrar indicador
            app.Index_AP1.Value = VWBA;
            
            % Status de procesamiento
            app.StatusProcess(1) = true;
        end

        % Button pushed function: GDB_AP_2_CO2e_B
        function GDB_AP_2_CO2e_BPushed(app, event)
            % Estimar valor de carbono equivalente en la condición BaU
            % utilizando los datos de base de datos
            app.Params_AP2_CO2e_B.Value = CO2e_Without_AgriPrac(app);
            
        end

        % Button pushed function: GDB_AP_2_CO2e_P
        function GDB_AP_2_CO2e_PPushed(app, event)
            % Estimar valor de carbono equivalente en la condición SbN
            % utilizando los datos de base de datos
            app.Params_AP2_CO2e_P.Value = CO2e_With_AgriPrac(app);
        end

        % Button pushed function: GDB_AP_2_PWA
        function GDB_AP_2_PWAPushed(app, event)
            % According with Bagnall et al .(2022), for an increase in SOC 
            % of 10 g kg–1 (1%) in noncalcareous soils, an average increase 
            % in θAWHC of 3.0 mm 100 mm–1 soil (0.03 m3 m–3) on average across 
            % all soil texture classes was found. For the tool, a default 
            % value 3 ((m/m)/(kg/kg)).
            app.Params_AP2_PAW.Value = 3;
        end

        % Callback function
        function Button_GDB_RZDWPushed(app, event)
            % Estimar valor de peso en zona de raices
            % utilizando los datos de base de datos
            app.RZDW.Value = BD_Without_AgriPrac(app);
        end

        % Button pushed function: GDB_AP_2_RootDepth
        function GDB_AP_2_RootDepthPushed(app, event)
            % According to the root zone depth data reported by 
            % Haberle & Svoboda (2015), the mean value of the minimum range 
            % of the growing condition. The above gives a value of 68 cm. 
            % However, according to carbon change studies, the greatest 
            % changes occur in the first 15 cm of the soil. In order not 
            % to generate an overestimation of the benefits, a default 
            % value of 15 cm is considered in the tool.
            app.Params_AP2_RootDepth.Value = 0.15;
        end

        % Button pushed function: Processing_AP2
        function Processing_AP2Pushed(app, event)
            %  Factor ha -> m2
            Factor = 1E4;
            % CO2ewith-project (kg)
            InCO2e_P      = app.Params_AP2_CO2e_P.Value*1000;
            % CO2ebaseline (kg)
            InCO2e_B      = app.Params_AP2_CO2e_B.Value*1000;
            % Plant available water holding capacity coefficient ((m/m)/(kg/kg)) 
            InPWA         = app.Params_AP2_PAW.Value;
            % root zone depth (m)
            InRootDepth   = app.Params_AP2_RootDepth.Value;
            % root zone area (m^2)
            InRootArea    = app.Area_AgriPrac.Value*Factor;
            InNTR         = app.Params_AP2_NTR.Value;
            % Root zone dry weight (kg)
            InRZDW        = (InRootArea*InRootDepth)*BD_Without_AgriPrac(app);
            % Volume captured (m3)
            VC            = Method_A14(InCO2e_P,InCO2e_B,InRZDW,InPWA,InRootDepth,InRootArea,InNTR);
            app.Index_AP2.Value = VC;
            
            % Status de procesamiento
            app.StatusProcess(2) = true;
        end

        % Button pushed function: Button_WCN1
        function Button_WCN1Pushed(app, event)
            % Factor day to seg
            Factor1 = 24*3600;
            % Factor liter to m3
            Factor2 = 1/1000;
            % Cálcular indicador
            app.Index_WCN1.Value = (app.WCN1_Param_1.Value*Factor2)*(app.WCN1_Param_2.Value*Factor1);
            
            % Status de procesamiento
            app.StatusProcess(4) = true;
        end

        % Button pushed function: Button_WCN2
        function Button_WCN2Pushed(app, event)
            % Factor day to seg
            Factor1 = 24*3600;
            % Factor liter to m3
            Factor2 = 1/1000;
            % Cálcular indicador
            app.Index_WCN2.Value = ((app.WCN2_Param_1.Value - app.WCN2_Param_2.Value)*Factor2)*(app.WCN2_Param_3.Value*Factor1);

            % Status de procesamiento
            app.StatusProcess(5) = true;
        end

        % Button pushed function: Button_WCN3
        function Button_WCN3Pushed(app, event)
            % Factor day to seg
            Factor1 = 24*3600;
            % Factor liter to m3
            Factor2 = 1/1000;
            % Cálcular indicador
            app.Index_WCN3.Value = ((app.WCN3_Param_1.Value - app.WCN3_Param_2.Value)*Factor2)*(app.WCN3_Param_3.Value*Factor1);

            % Status de procesamiento
            app.StatusProcess(6) = true;
        end

        % Button pushed function: Button_WCN4
        function Button_WCN4Pushed(app, event)
            % Factor day to seg
            Factor1 = 24*3600;
            % Factor liter to m3
            Factor2 = 1/1000;
            % Cálcular indicador
            app.Index_WCN4.Value = ((app.WCN4_Param_1.Value - app.WCN4_Param_2.Value)*Factor2)*(app.WCN4_Param_3.Value*Factor1)*(1-app.WCN4_Param_4.Value);

            % Status de procesamiento
            app.StatusProcess(7) = true;
        end

        % Button pushed function: Button_WCN5
        function Button_WCN5Pushed(app, event)
            % Factor mm to m
            Factor1 = 1/1000;
            % Factor ha to m2
            Factor2 = 1E4;
            if app.WCN5_Param_4.Value == 0
                app.Index_WCN5.Value = ((app.WCN5_Param_1.Value - app.WCN5_Param_2.Value)*Factor1)*(app.WCN5_Param_3.Value*Factor2);
            else
                app.Index_WCN5.Value = ((app.WCN5_Param_1.Value - app.WCN5_Param_2.Value)*Factor1)*(app.WCN5_Param_3.Value*Factor2)*app.WCN5_Param_4.Value;
            end

            % Status de procesamiento
            app.StatusProcess(8) = true;

        end

        % Callback function
        function Button_GGI1Pushed(app, event)
            % Factor mm to m
            Factor1 = 1/1000;
            % Factor ha to m2
            Factor2 = 1E4;
            app.Index_GGI1.Value = (app.GGI1_Param_1.Value*Factor1)*(app.GGI1_Param_2.Value*Factor2)*(app.GGI1_Param_3.Value)*app.GGI1_Param_4.Value;

        end

        % Button pushed function: Processing_AP3
        function Processing_AP3Pushed(app, event)
            % Post-Project Runoff or Post-Project Recharge (m3)
            InQ     = app.Param_AP3_WAR.Value;
            % Incremental Improvement by Activity 
            InIIA   = app.Param_AP3_IIA.Value;
            % Total Improvement Needed 
            InTIN   = app.Param_AP3_TIN.Value;
            % Number of WQ Challenges Addressed by Activity
            InNA    = app.Param_AP3_NA.Value;
            % Number of WQ Challenges Caused by Land Use
            InNL    = app.Param_AP3_NL.Value;
            % Volume Improved (m3)
            app.Index_AP3.Value = Method_A13(InQ, InIIA, InTIN, InNA, InNL);

            % Status de procesamiento
            app.StatusProcess(3) = true;
        end

        % Callback function
        function GGI1_GDBButtonPushed(app, event)
            TS_Path = fullfile(app.ProjectPath,'02-Biophysic','P.csv');

            % Leer serie de tiempo
            [ErrorStatus, P, Date] = app.Read_TS(TS_Path);            
            if ErrorStatus
                return
            end
            
            app.GGI1_Param_1.Value = mean(sum(P.*(year(Date)==unique(year(Date))')));

        end

        % Value changed function: ChallengesSpinner
        function ChallengesSpinnerValueChanged(app, event)
            value   = app.ChallengesSpinner.Value;

            Data    = app.GGI2_Table.Data;
            n       = size(Data,1);

            if value > n
                app.GGI2_Table.Data = [(1:value)', [Data(1:n,2); zeros(value-n,1)]];
            else
                app.GGI2_Table.Data = Data(1:value,:);
                
            end
        end

        % Button pushed function: Button_Processing_Ax1_11
        function Button_Processing_Ax1_11Pushed(app, event)
            Data = app.GGI2_Table.Data;
            
            %Check
            if (sum(Data(:,2) > 1)>0)||(sum(Data(:,2) < 0)>0)
                errordlg('The fraction inproved must be between 0 and 1')
                return
            end

            %Check
            if sum(isnan(Data(:,2))) > 1
                errordlg('The fraction inproved must be have values different to NaN')
                return
            end
            
            % Asignar valor de indicador 
            app.Index_GGI2.Value = app.GGI2_Param_1.Value*mean(Data(:,2));
            
            % Status de procesamiento
            app.StatusProcess(10) = true;
        end

        % Button pushed function: Button_GDB_CN_H_2
        function Button_GDB_CN_H_2Pushed(app, event)
            % Average CN
            app.CN_H_2.Value    = CN_Without_AgriPrac(app);
        end

        % Button pushed function: Button_GDB_CN_F_2
        function Button_GDB_CN_F_2Pushed(app, event)
            % Average CN
            Path_Porfolio       = fullfile(app.ProjectPath,'03-Porfolio','Portfolio_CR.tif');
            app.CN_F_2.Value    = CN_With_CR(app,Path_Porfolio);
        end

        % Button pushed function: Button_GDB_TS_14
        function Button_GDB_TS_14Pushed(app, event)
            % Asignar ruta al campo
            app.Edit_TS_Path_2.Value = fullfile(app.ProjectPath,'02-Biophysic','P.csv');
        end

        % Button pushed function: Button_Load_TS_2
        function Button_Load_TS_2Pushed(app, event)
            % Leer Serie de precipitación
            % Abrir explorador y leer proyecto en formato .mat
            [FileProject, PathFile] = uigetfile('C:\*.csv','Select precipitation file');

            % Asignar ruta de la carpeta
            if ~(FileProject == 0)
                % Asignar ruta al campo
                app.Edit_TS_Path_2.Value = fullfile(PathFile, FileProject);
            end
        end

        % Button pushed function: Button_CR
        function Button_CRPushed(app, event)
            % Leer datos de coberturas historicas
            CNH = app.CN_H_2.Value;

            % Leer datos de coberturas futuras
            CNF = app.CN_F_2.Value;
            
            % ruta del archivo
            TS_Path = app.Edit_TS_Path_2.Value;

            % Leer serie de tiempo
            [ErrorStatus, P, Date] = app.Read_TS(TS_Path);            
            if ErrorStatus
                return
            end

            % Ejecutar Metodo Anexo 1 - Histórico
            QH = Method_A1(P, CNH);
            
            % Ejecutar Metodo Anexo 1 - Futuro
            QF = Method_A1(P, CNF);
            
            % Indicador
            VWBA = mean(sum(QH.*(year(Date)==unique(year(Date))'))) - ...
                   mean(sum(QF.*(year(Date)==unique(year(Date))')));

            VWBA = (VWBA/1000)*app.BasinArea;
            
            % Mostrar indicador
            app.Index_CR.Value = VWBA;

            % Status de procesamiento
            app.StatusProcess(16) = true;
        end

        % Button pushed function: Button_Porfolio_CR
        function Button_Porfolio_CRPushed(app, event)
            % Cambiar estado
            app.Lamp_CR.Color = [0.9 0.9 0.9];
            
            % Leer valor de área para portafolio en ha
            Ap = app.Area_CR.Value;

            % Check que el área no se cero
            if Ap == 0
                errordlg('The value of the area must be greater than zero "0"')
                return
            end
                
            % Crear portafolio
            PorPath = fullfile(app.ProjectPath,'03-Porfolio','Portfolio_CR.tif');
            [CheckArea,TotalArea,PixelArea] = Porfolio_CR(app,Ap,PorPath);

            if CheckArea
                app.Area_CR.Value = floor(TotalArea*PixelArea/1E4);
            end

            % Cambiar estado
            app.Lamp_CR.Color = [0 1 0];

            % Habilitar panel de indicador
            app.Panel_CR.Enable = "on";
        end

        % Button pushed function: Button_GDB_TS_P_CR
        function Button_GDB_TS_P_CRPushed(app, event)
            % Asignar ruta al campo
            app.TS_Path_P_CR.Value = fullfile(app.ProjectPath,'02-Biophysic','P.csv');
        end

        % Button pushed function: Button_Load_TS_P_CR
        function Button_Load_TS_P_CRPushed(app, event)
            % Leer Serie de precipitación
            % Abrir explorador y leer proyecto en formato .mat
            [FileProject, PathFile] = uigetfile('C:\*.csv','Select precipitation file');

            % Asignar ruta de la carpeta
            if ~(FileProject == 0)
                % Asignar ruta al campo
                app.TS_Path_P_CR.Value = fullfile(PathFile, FileProject);
            end
        end

        % Button pushed function: Button_GDB_CN_H_CR
        function Button_GDB_CN_H_CRPushed(app, event)
            % Average CN
            app.CN_H_CR.Value = CN_Without_AgriPrac(app);
        end

        % Button pushed function: Button_GDB_CN_P_CR
        function Button_GDB_CN_P_CRPushed(app, event)
            % Average CN
            Path_Porfolio       = fullfile(app.ProjectPath,'03-Porfolio','Portfolio_CR.tif');
            app.CN_F_CR.Value   = CN_With_CR(app,Path_Porfolio);
        end

        % Button pushed function: Button_GDB_CS_CR
        function Button_GDB_CS_CRPushed(app, event)

            Processing_TS_CS(app);
            app.TS_Path_CS_CR.Value = fullfile(app.ProjectPath,'02-Biophysic','CS.csv');
           
        end

        % Button pushed function: Button_GDB_FC_CR
        function Button_GDB_FC_CRPushed(app, event)
            % CC
            app.CC_CR.Value = CC_Avg(app);
        end

        % Button pushed function: Button_GDB_Smax_CR
        function Button_GDB_Smax_CRPushed(app, event)
            app.Smax_CR.Value = Smax_Avg(app);
        end

        % Button pushed function: WCN5_GDB_3
        function WCN5_GDB_3ButtonPushed(app, event)
            % Leer precipitación
            TS_Path = fullfile(app.ProjectPath,'02-Biophysic','P.csv');

            % Leer serie de tiempo
            [ErrorStatus, P, Date] = app.Read_TS(TS_Path);            
            if ErrorStatus
                return
            end
            
            % Precipitación promedio anual multianual (mm)
            Pm = mean(sum(P.*(year(Date)==unique(year(Date))')));

            % Porcentage de área de bosque
            f = app.ForestArea/app.BasinArea;

            % Evapotranspiración
            app.WCN5_Param_1.Value = ETP_Zhang(Pm,f);
            
        end

        % Button pushed function: WCN5_GDB_4
        function WCN5_GDB_4ButtonPushed(app, event)
            msgbox('Note that this calculation takes into account the affected area data ');

            % Leer precipitación
            TS_Path = fullfile(app.ProjectPath,'02-Biophysic','P.csv');
            
            % Leer serie de tiempo
            [ErrorStatus, P, Date] = app.Read_TS(TS_Path);            
            if ErrorStatus
                return
            end
            
            % Precipitación promedio anual multianual (mm)
            Pm = mean(sum(P.*(year(Date)==unique(year(Date))')));
            
            %  Factor ha -> m2
            Factor = 1E4;

            % Porcentage de área de bosque
            AA = (app.WCN5_Param_3.Value*Factor);
            if AA > app.ForestArea
                msgbox(['The area of forest in the basin is: ',num2str(app.ForestArea/Factor,'%d'),' ha']);
                AA = app.ForestArea;
            end
            f = (app.ForestArea - AA)/app.BasinArea;
            
            % Evapotranspiración
            app.WCN5_Param_2.Value = ETP_Zhang(Pm,f);

        end

        % Button pushed function: Button_GDB_TS__ET_CR
        function Button_GDB_TS__ET_CRPushed(app, event)
            % Asignar ruta al campo
            app.TS_Path_ET_CR.Value = fullfile(app.ProjectPath,'02-Biophysic','ETP.csv');
        end

        % Button pushed function: Button_Load_TS_ET_CR
        function Button_Load_TS_ET_CRPushed(app, event)
            % Leer Serie de evapotranspiración
            % Abrir explorador y leer proyecto en formato .mat
            [FileProject, PathFile] = uigetfile('C:\*.csv','Select evapotrasnpiration file');

            % Asignar ruta de la carpeta
            if ~(FileProject == 0)
                % Asignar ruta al campo
                app.TS_Path_ET_CR.Value = fullfile(PathFile, FileProject);
            end
        end

        % Button pushed function: Button_Load_TS_CS_CR
        function Button_Load_TS_CS_CRPushed(app, event)
            % Leer Serie de canopy storage
            % Abrir explorador y leer proyecto en formato .mat
            [FileProject, PathFile] = uigetfile('C:\*.csv','Select canopy storage file');

            % Asignar ruta de la carpeta
            if ~(FileProject == 0)
                % Asignar ruta al campo
                app.TS_Path_CS_CR.Value = fullfile(PathFile, FileProject);
            end
        end

        % Button pushed function: Button_Processing_4
        function Button_Processing_4Pushed(app, event)
            ProgressBar = waitbar(0, 'Processing ...','Color',[1 1 1]);
            wbch        = allchild(ProgressBar);
            jp          = wbch(1).JavaPeer;
            jp.setIndeterminate(1)

            % Leer precipitación [mm]
            TS_Path = app.TS_Path_P_CR.Value;
            % Leer serie de tiempo
            [ErrorStatus, P, Date] = app.Read_TS(TS_Path);            
            if ErrorStatus
                return
            end

            % Leer evapotrasnpiración [mm]
            TS_Path = app.TS_Path_ET_CR.Value;
            % Leer serie de tiempo
            [ErrorStatus, ET, Date] = app.Read_TS(TS_Path);            
            if ErrorStatus
                return
            end                      
            
            % Leer canopy storage [mm]
            TS_Path = app.TS_Path_CS_CR.Value;
            % Leer serie de tiempo
            [ErrorStatus, CS, Date] = app.Read_TS(TS_Path);            
            if ErrorStatus
                return
            end

            % Leer CN de cobeturas actuales [Ad]
            CNH = app.CN_H_CR.Value;

            % Leer CN de coberturas futuras [Ad]
            CNF = app.CN_F_CR.Value;
            
            % Ejecutar Metodo Anexo 1 - Histórico [mm]
            QH = Method_A1(P, CNH);
            
            % Ejecutar Metodo Anexo 1 - Futuro [mm]
            QF = Method_A1(P, CNF);

            % Leer Smax [mm]
            Smax = app.Smax_CR.Value;

            % Leer CC [mm]
            FC = app.CC_CR.Value;
            
            % Punto de marchitez permanente
            PWP = app.PWP_CR.Value;

            % Conductividad hidráulica [mm/d]
            Ks = app.Rr_CR.Value;

            % f = app.ForestArea/app.BasinArea;
            % ET = ETP_Zhang(P - QH,f);
            % ET(isnan(ET)) = 0;

            % Recarga [mm] y contenido de agua en el suelo [mm] para la 
            % condición historica
            [RH, SwH, ~] = Method_A15(P, ET, CS, QH, Smax, FC, PWP, Ks);
            
            % Recarga [mm] y contenido de agua en el suelo [mm] para la 
            % condición con SbN
            [RF, SwF, ~] = Method_A15(P, ET, CS, QF, Smax, FC, PWP, Ks);
            
            % SPI
            [DateSPI, SPI, DroughtClass] = Index_SPI(Date, P, 'Index','SPI','SizeStep',1);                                   

            % Recarga y agua en el suelo
            [VWB_R, VWB_Sw] = Plot_TS_SWY(app.ProjectPath, DateSPI, SPI, DroughtClass, Date, P, SwH, SwF, RH, RF, app.BasinArea, PWP, true);
            
            % Indicador de recarga
            app.Index_CR_R.Value    = VWB_R;

            % Indicador de agua en el suelo
            app.Index_CR_Sw.Value   = VWB_Sw;

            % Cerrar progress bar
            close(ProgressBar)

            % Status de procesamiento
            app.StatusProcess(17) = true;
            app.StatusProcess(18) = true;
        end

        % Button pushed function: Button_GDB_Rr_CR
        function Button_GDB_Rr_CRPushed(app, event)
            app.Rr_CR.Value = Ks_Avg(app);
        end

        % Button pushed function: Button_GDB_PWA_CR
        function Button_GDB_PWA_CRPushed(app, event)
            app.PWP_CR.Value = PWP_Avg(app);
        end

        % Button pushed function: Button_GDB_RPerCapita
        function Button_GDB_RPerCapitaPushed(app, event)
            % La Organización Mundial de la Salud y el Fondo de las Naciones 
            % Unidas para la Infancia (OMS y UNICEF 2000) definen el acceso 
            % razonable como la disponibilidad de al menos 20 litros por 
            % persona por día de una fuente dentro de un kilómetro de la 
            % vivienda del usuario. 
            app.GGI3_Param_2.Value = 20;
        end

        % Button pushed function: Button_GGI3
        function Button_GGI3Pushed(app, event)
            % Litros -> m3
            Factor = 1/1000;
            % indicador
            app.Index_GGI3.Value = (app.GGI3_Param_1.Value*app.GGI3_Param_2.Value*app.GGI3_Param_3.Value)*Factor;

            % Status de procesamiento
            app.StatusProcess(11) = true;
        end

        % Button pushed function: Button_GDB_RPerCapita_2
        function Button_GDB_RPerCapita_2Pushed(app, event)
            % La Organización Mundial de la Salud y el Fondo de las Naciones 
            % Unidas para la Infancia (OMS y UNICEF 2000) definen el acceso 
            % razonable como la disponibilidad de al menos 20 litros por 
            % persona por día de una fuente dentro de un kilómetro de la 
            % vivienda del usuario. 
            app.WASH2_Param_2.Value = 20;
        end

        % Button pushed function: Button_WASH2
        function Button_WASH2Pushed(app, event)
            % Litros -> m3
            Factor = 1/1000;

            % Indicaor
            app.Index_WASH2.Value = (app.WASH2_Param_1.Value*app.WASH2_Param_2.Value*app.WASH2_Param_3.Value)*Factor;

            % Status de procesamiento
            app.StatusProcess(19) = true;
        end

        % Callback function
        function Button_GDB_CN_H_3Pushed(app, event)
            % Average CN
            app.CN_H_3.Value = CN_Without_AgriPrac(app);
        end

        % Value changed function: ChallengesSpinner_2
        function ChallengesSpinner_2ValueChanged(app, event)
            value = app.ChallengesSpinner_2.Value;

            Data    = app.WASH_Table.Data;
            n       = size(Data,1);

            if value > n
                app.WASH_Table.Data = [(1:value)', [Data(1:n,2); zeros(value-n,1)]];
            else
                app.WASH_Table.Data = Data(1:value,:);
                
            end
        end

        % Button pushed function: Button_WASH
        function Button_WASHPushed(app, event)
            Data = app.WASH_Table.Data;
            
            %Check
            if (sum(Data(:,2) > 1)>0)||(sum(Data(:,2) < 0)>0)
                errordlg('The fraction inproved must be between 0 and 1')
                return
            end

            %Check
            if sum(isnan(Data(:,2))) > 1
                errordlg('The fraction inproved must be have values different to NaN')
                return
            end
            
            app.Index_WASH.Value = app.WASH_Param_1.Value*mean(Data(:,2));

            % Status de procesamiento
            app.StatusProcess(20) = true;
        end

        % Button pushed function: Button_GGI1
        function Button_GGI1Pushed2(app, event)
            % mm -> m
            Factor1 = 1/1000;
            % ha -> m2
            Factor2 = 1E4;
            % Inicador
            app.Index_GGI1.Value = (app.GGI1_Param_1.Value*Factor1)*(app.GGI1_Param_2.Value*Factor2)*app.GGI1_Param_3.Value*app.GGI1_Param_4.Value;

            % Status de procesamiento
            app.StatusProcess(9) = true;
        end

        % Button pushed function: Button_GDB_GG1
        function Button_GDB_GG1Pushed(app, event)
            % Leer precipitación
            TS_Path = fullfile(app.ProjectPath,'02-Biophysic','P.csv');
            % Leer serie de tiempo
            [ErrorStatus, P, Date] = app.Read_TS(TS_Path);            
            if ErrorStatus
                return
            end
            
            % Precipitación
            app.GGI1_Param_1.Value = mean(sum(P.*(year(Date)==unique(year(Date))')));

        end

        % Button pushed function: Button_GGI5
        function Button_GGI5Pushed(app, event)
            % ha -> m2
            Factor = 1E4;
            % Si proyecto
            H = (app.GGI5_Param_1.Value*Factor)*app.GGI5_Param_2.Value*app.GGI5_Param_4.Value;
            % Con proyecto
            F = (app.GGI5_Param_1.Value*Factor)*app.GGI5_Param_3.Value*app.GGI5_Param_4.Value;
            % Indicador
            app.Index_GGI5.Value = H - F;

            % Status de procesamiento
            app.StatusProcess(13) = true;
        end

        % Button pushed function: Button_GGI4
        function Button_GGI4Pushed(app, event)
            % Litros a m3
            Factor1 = 1/1000;
            % day -> seg
            Factor2 = 3600*24;
            % Indicador
            app.Index_GGI4.Value = (app.GGI4_Param_2.Value - app.GGI4_Param_1.Value)*app.GGI4_Param_3.Value*Factor1*Factor2;

            % Status de procesamiento
            app.StatusProcess(12) = true;
        end

        % Button pushed function: Button_GGI7
        function Button_GGI7Pushed(app, event)
            % mm -> m
            Factor1 = 1/1000; 
            % ha -> m2
            Factor2 = 1E4; 
            % Available supply 
            AS      = (app.GGI7_Param_1.Value*Factor1)*(app.GGI7_Param_2.Value*Factor2)*app.GGI7_Param_3.Value;
            % Storage potential
            SP      = app.GGI7_Param_6.Value*app.GGI7_Param_7.Value;
            % Volume captured 
            VC      = min([AS SP]);
            % Recharge volume 
            RV      = VC - (app.GGI7_Param_4.Value*Factor1) - app.GGI7_Param_5.Value;
            % indicador
            app.Index_GGI7.Value = RV;

            % Status de procesamiento
            app.StatusProcess(14) = true;
        end

        % Button pushed function: GDB_AP3_VI
        function GDB_AP3_VIPushed(app, event)
            app.GDB_AP1_CN_HPushed;
            
            % Leer datos de coberturas futuras
            CNF = app.Params_AP1_CN_H.Value;
            
            % ruta del archivo
            TS_Path = fullfile(app.ProjectPath,'02-Biophysic','P.csv'); 

            % Leer serie de tiempo
            [ErrorStatus, P, Date] = app.Read_TS(TS_Path);            
            if ErrorStatus
                return
            end
            
            % Ejecutar Metodo Anexo 1 - Futuro
            QF = (Method_A1(P, CNF)/1000)*app.BasinArea;
            
            % Indicador
            app.Param_AP3_WAR.Value = mean(sum(QF.*(year(Date)==unique(year(Date))')));

        end

        % Menu selected function: CreatereportMenu
        function CreatereportMenuSelected(app, event)
            app.Disable_Project;
            App_4_Report(app);
        end

        % Button pushed function: Button_GDB_GG6_1
        function Button_GDB_GG6_1Pushed(app, event)
            
            % Leer datos de coberturas futuras
            CNF = CN_Without_AgriPrac(app);
            
            % ruta del archivo
            TS_Path = fullfile(app.ProjectPath,'02-Biophysic','P.csv'); 

            % Leer serie de tiempo
            [ErrorStatus, P, Date] = app.Read_TS(TS_Path);            
            if ErrorStatus
                return
            end
            
            % Ejecutar Metodo Anexo 1 - Futuro
            QF = (Method_A1(P, CNF)/1000)*app.BasinArea;
            
            % Indicador
            app.GGI6_Param_1.Value = mean(sum(QF.*(year(Date)==unique(year(Date))')));

        end

        % Button pushed function: Button_Processing_GGI6
        function Button_Processing_GGI6Pushed(app, event)
            % Post-Project Runoff or Post-Project Recharge (m3)
            InQ     = app.GGI6_Param_1.Value;
            % Incremental Improvement by Activity 
            InIIA   = app.GGI6_Param_2.Value;
            % Total Improvement Needed 
            InTIN   = app.GGI6_Param_3.Value;
            % Number of WQ Challenges Addressed by Activity
            InNA    = app.GGI6_Param_4.Value;
            % Number of WQ Challenges Caused by Land Use
            InNL    = app.GGI6_Param_5.Value;
            % Volume Improved (m3)
            app.Index_GGI6.Value = Method_A13(InQ, InIIA, InTIN, InNA, InNL);

            % Status de procesamiento
            app.StatusProcess(15) = true;
        end

        % Button pushed function: Button_GDB_GG7_1
        function Button_GDB_GG7_1Pushed(app, event)
            % Leer precipitación
            TS_Path = fullfile(app.ProjectPath,'02-Biophysic','P.csv');
            
            % Leer serie de tiempo
            [ErrorStatus, P, Date] = app.Read_TS(TS_Path);            
            if ErrorStatus
                return
            end
            
            % Precipitación
            app.GGI7_Param_1.Value = mean(sum(P.*(year(Date)==unique(year(Date))')));
        end

        % Button pushed function: Button_GDB_GG7_2
        function Button_GDB_GG7_2Pushed(app, event)
            % m2 -> ha
            Factor = 1/1E4;
            % Area de la cuenca de análisis
            app.GGI7_Param_2.Value = app.BasinArea*Factor;

        end

        % Menu selected function: DataBaseMenu
        function DataBaseMenuSelected(app, event)
            web('https://tnc.box.com/s/4rz6v03xner2jnmuxycp1ipniyezrskg')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewProjectMenu = uimenu(app.FileMenu);
            app.NewProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewProjectMenuSelected, true);
            app.NewProjectMenu.Text = 'New Project';
            app.OpenProjectMenu = uimenu(app.FileMenu);
            app.OpenProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenProjectMenuSelected, true);
            app.OpenProjectMenu.Text = 'Open Project';
            app.EditProjectMenu = uimenu(app.FileMenu);
            app.EditProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @EditProjectMenuSelected, true);
            app.EditProjectMenu.Enable = 'off';
            app.EditProjectMenu.Text = 'Edit Project';
            app.BasinMenu = uimenu(app.UIFigure);
            app.BasinMenu.Text = 'Basin';
            app.CreatebasinMenu = uimenu(app.BasinMenu);
            app.CreatebasinMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatebasinMenuSelected, true);
            app.CreatebasinMenu.Enable = 'off';
            app.CreatebasinMenu.Text = 'Create basin';
            app.EditBasinMenu = uimenu(app.BasinMenu);
            app.EditBasinMenu.MenuSelectedFcn = createCallbackFcn(app, @EditBasinMenuSelected, true);
            app.EditBasinMenu.Enable = 'off';
            app.EditBasinMenu.Text = 'Edit Basin';
            app.ReportMenu = uimenu(app.UIFigure);
            app.ReportMenu.Text = 'Report';
            app.CreatereportMenu = uimenu(app.ReportMenu);
            app.CreatereportMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatereportMenuSelected, true);
            app.CreatereportMenu.Enable = 'off';
            app.CreatereportMenu.Text = 'Create report';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DataBaseMenu = uimenu(app.HelpMenu);
            app.DataBaseMenu.MenuSelectedFcn = createCallbackFcn(app, @DataBaseMenuSelected, true);
            app.DataBaseMenu.Text = 'DataBase';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_N4W-Facility.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Logo_TNC.png';
            app.VolumetricWaterBenefitAccountingLabel = uilabel(app.UIFigure);
            app.VolumetricWaterBenefitAccountingLabel.Text = {'Volumetric Water Benefit Accounting'; '& TNC Freshwater Metrics Estimation'};
            app.ProjectNameEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectNameEditFieldLabel.Text = 'Project Name';
            app.ProjectName = uieditfield(app.UIFigure, 'text');
            app.ProjectName.Editable = 'off';
            app.Panel_Index = uipanel(app.UIFigure);
            app.Panel_Index.Enable = 'off';
            app.TabGroup = uitabgroup(app.Panel_Index);
            app.AgriculturalpracticesTab = uitab(app.TabGroup);
            app.AgriculturalpracticesTab.Title = 'Agricultural practices';
            app.Label = uilabel(app.AgriculturalpracticesTab);
            app.Label.Text = 'Agricultural practices includ sub-activities with agriculture BMPs including cover crops, mulching, reduced or no till, laser leveling, terraced/contour planting, agroforestry, regenerative agriculture, grazing management.';
            app.Panel_AgriPrac = uipanel(app.AgriculturalpracticesTab);
            app.Panel_AgriPrac.Enable = 'off';
            app.Panel_Index_AP = uitabgroup(app.Panel_AgriPrac);
            app.AP1 = uitab(app.Panel_Index_AP);
            app.AP1.Title = 'Reduced runoff';
            app.Label_2 = uilabel(app.AP1);
            app.Label_2.Text = 'Curve number method';
            app.Label_3 = uilabel(app.AP1);
            app.Label_3.Text = 'The Curve Number Runoff method is an empirical method for estimating runoff quantities based on land cover, use, soil, and slope, accounting for temporal changes in precipitation and soil water content';
            app.CurvenumberRasterLabel = uilabel(app.AP1);
            app.CurvenumberRasterLabel.Text = {'Curve number -'; 'without activities'};
            app.CurvenumberWithActivitiesLabel = uilabel(app.AP1);
            app.CurvenumberWithActivitiesLabel.Text = {'Curve number -'; 'with activities'};
            app.Label_8 = uilabel(app.AP1);
            app.Label_8.Text = 'Agricultural practices includ sub-activities with agriculture BMPs including cover crops, mulching, reduced or no till, laser leveling, terraced/contour planting, agroforestry, regenerative agriculture, grazing management.';
            app.Label_9 = uilabel(app.AP1);
            app.Label_9.Text = 'Sub-activities';
            app.Label_10 = uilabel(app.AP1);
            app.Label_10.Text = 'Improved water quality through nonpoint source pollution reduction';
            app.Label_11 = uilabel(app.AP1);
            app.Label_11.Text = {'Volumetric '; 'objective '};
            app.Hyperlink = uihyperlink(app.AP1);
            app.Hyperlink.URL = 'https://tnc.box.com/s/j64zeqqka9a8b75v6vn88ocbga9ftsac';
            app.Hyperlink.Text = 'Appendix A-1';
            app.TimeseriesdailyprecipitationcsvLabel = uilabel(app.AP1);
            app.TimeseriesdailyprecipitationcsvLabel.Text = {'Time series daily '; 'precipitation (csv)'};
            app.Ouputs_AP1 = uipanel(app.AP1);
            app.Ouputs_AP1.Title = 'Ouputs';
            app.Processing_AP1 = uibutton(app.Ouputs_AP1, 'push');
            app.Processing_AP1.ButtonPushedFcn = createCallbackFcn(app, @Processing_AP1Pushed, true);
            app.Processing_AP1.Icon = 'Icons_Play.png';
            app.Processing_AP1.Text = 'Processing';
            app.Reducedrunoffm3Label = uilabel(app.Ouputs_AP1);
            app.Reducedrunoffm3Label.Interpreter = 'latex';
            app.Reducedrunoffm3Label.Text = '\bf VWB $\bf (m^{3}/yr)$';
            app.Index_AP1 = uieditfield(app.Ouputs_AP1, 'numeric');
            app.Index_AP1.Editable = 'off';
            app.Load_AP1_TS_P = uibutton(app.AP1, 'push');
            app.Load_AP1_TS_P.ButtonPushedFcn = createCallbackFcn(app, @Load_AP1_TS_PPushed, true);
            app.Load_AP1_TS_P.Icon = 'Icons_NewProject.png';
            app.Load_AP1_TS_P.Text = 'Load';
            app.GDB_AP1_TS_P = uibutton(app.AP1, 'push');
            app.GDB_AP1_TS_P.ButtonPushedFcn = createCallbackFcn(app, @GDB_AP1_TS_PPushed, true);
            app.GDB_AP1_TS_P.Icon = 'Icons_DataBase.png';
            app.GDB_AP1_TS_P.Text = {'Calculate from '; 'Global Database'};
            app.GDB_AP1_CN_F = uibutton(app.AP1, 'push');
            app.GDB_AP1_CN_F.ButtonPushedFcn = createCallbackFcn(app, @GDB_AP1_CN_FPushed, true);
            app.GDB_AP1_CN_F.Icon = 'Icons_DataBase.png';
            app.GDB_AP1_CN_F.Text = {'Calculate from '; 'Global Database'};
            app.GDB_AP1_CN_H = uibutton(app.AP1, 'push');
            app.GDB_AP1_CN_H.ButtonPushedFcn = createCallbackFcn(app, @GDB_AP1_CN_HPushed, true);
            app.GDB_AP1_CN_H.Icon = 'Icons_DataBase.png';
            app.GDB_AP1_CN_H.Text = {'Calculate from '; 'Global Database'};
            app.Params_AP1_TS_Path = uieditfield(app.AP1, 'text');
            app.Params_AP1_TS_Path.Editable = 'off';
            app.Params_AP1_CN_F = uieditfield(app.AP1, 'numeric');
            app.Params_AP1_CN_F.Limits = [0 100];
            app.Params_AP1_CN_H = uieditfield(app.AP1, 'numeric');
            app.Params_AP1_CN_H.Limits = [0 100];
            app.AP2 = uitab(app.Panel_Index_AP);
            app.AP2.Title = 'Volume captured';
            app.Label_12 = uilabel(app.AP2);
            app.Label_12.Text = 'Agricultural practices includ sub-activities with agriculture BMPs including cover crops, mulching, reduced or no till, laser leveling, terraced/contour planting, agroforestry, regenerative agriculture, grazing management.';
            app.Label_13 = uilabel(app.AP2);
            app.Label_13.Text = 'Sub-activities';
            app.Label_14 = uilabel(app.AP2);
            app.Label_14.Text = 'Improved water balance through increased supply';
            app.Label_15 = uilabel(app.AP2);
            app.Label_15.Text = {'Volumetric '; 'objective '};
            app.Label_20 = uilabel(app.AP2);
            app.Label_20.Text = 'Soil Water Holding Capacity Method';
            app.Label_21 = uilabel(app.AP2);
            app.Label_21.Text = 'This method is used to estimate the volume of water associated with activities that improve or restore soil health. Similar to how lakes, rivers and aquifers provide critical storage of freshwater resources, healthy soils also capture and store freshwater that is made available to plants and microfauna';
            app.Hyperlink_6 = uihyperlink(app.AP2);
            app.Hyperlink_6.URL = 'https://tnc.box.com/s/721k60kl8dk0uhul2qpc99pzalkxmkoz';
            app.Hyperlink_6.Text = 'Appendix A-14';
            app.Ouputs_AP2 = uipanel(app.AP2);
            app.Ouputs_AP2.Title = 'Ouputs';
            app.Processing_AP2 = uibutton(app.Ouputs_AP2, 'push');
            app.Processing_AP2.ButtonPushedFcn = createCallbackFcn(app, @Processing_AP2Pushed, true);
            app.Processing_AP2.Icon = 'Icons_Play.png';
            app.Processing_AP2.Text = 'Processing';
            app.VolumenrunoffLabel = uilabel(app.Ouputs_AP2);
            app.VolumenrunoffLabel.Interpreter = 'latex';
            app.VolumenrunoffLabel.Text = '\bf VWB $\bf (m^{3}/yr)$';
            app.Index_AP2 = uieditfield(app.Ouputs_AP2, 'numeric');
            app.Index_AP2.Editable = 'off';
            app.GDB_AP_2_RootDepth = uibutton(app.AP2, 'push');
            app.GDB_AP_2_RootDepth.ButtonPushedFcn = createCallbackFcn(app, @GDB_AP_2_RootDepthPushed, true);
            app.GDB_AP_2_RootDepth.Icon = 'Icons_DataBase.png';
            app.GDB_AP_2_RootDepth.Text = {'Calculate from '; 'Global Database'};
            app.GDB_AP_2_PWA = uibutton(app.AP2, 'push');
            app.GDB_AP_2_PWA.ButtonPushedFcn = createCallbackFcn(app, @GDB_AP_2_PWAPushed, true);
            app.GDB_AP_2_PWA.Icon = 'Icons_DataBase.png';
            app.GDB_AP_2_PWA.Text = {'Calculate from '; 'Global Database'};
            app.GDB_AP_2_CO2e_P = uibutton(app.AP2, 'push');
            app.GDB_AP_2_CO2e_P.ButtonPushedFcn = createCallbackFcn(app, @GDB_AP_2_CO2e_PPushed, true);
            app.GDB_AP_2_CO2e_P.Icon = 'Icons_DataBase.png';
            app.GDB_AP_2_CO2e_P.Text = {'Calculate from '; 'Global Database'};
            app.GDB_AP_2_CO2e_B = uibutton(app.AP2, 'push');
            app.GDB_AP_2_CO2e_B.ButtonPushedFcn = createCallbackFcn(app, @GDB_AP_2_CO2e_BPushed, true);
            app.GDB_AP_2_CO2e_B.Icon = 'Icons_DataBase.png';
            app.GDB_AP_2_CO2e_B.Text = {'Calculate from '; 'Global Database'};
            app.CO2eWithActivitiesLabel_13 = uilabel(app.AP2);
            app.CO2eWithActivitiesLabel_13.Text = {'Number of '; 'times recharged '};
            app.Params_AP2_NTR = uieditfield(app.AP2, 'numeric');
            app.Params_AP2_NTR.Limits = [0 Inf];
            app.CO2eWithActivitiesLabel = uilabel(app.AP2);
            app.CO2eWithActivitiesLabel.Text = {'CO2e - without '; 'activities (MT)'};
            app.CO2eWithActivitiesLabel_2 = uilabel(app.AP2);
            app.CO2eWithActivitiesLabel_2.Text = {'CO2e - with '; 'activities (MT)'};
            app.CO2eWithActivitiesLabel_4 = uilabel(app.AP2);
            app.CO2eWithActivitiesLabel_4.Text = {'Plant available'; 'water coef (%) '};
            app.CO2eWithActivitiesLabel_5 = uilabel(app.AP2);
            app.CO2eWithActivitiesLabel_5.Text = {'Root zone '; 'depth (m)'};
            app.Params_AP2_RootDepth = uieditfield(app.AP2, 'numeric');
            app.Params_AP2_RootDepth.Limits = [0 Inf];
            app.Params_AP2_PAW = uieditfield(app.AP2, 'numeric');
            app.Params_AP2_PAW.Limits = [0 Inf];
            app.Params_AP2_CO2e_P = uieditfield(app.AP2, 'numeric');
            app.Params_AP2_CO2e_P.Limits = [0 Inf];
            app.Params_AP2_CO2e_B = uieditfield(app.AP2, 'numeric');
            app.Params_AP2_CO2e_B.Limits = [0 Inf];
            app.AP3 = uitab(app.Panel_Index_AP);
            app.AP3.Title = 'Volume improved';
            app.Label_16 = uilabel(app.AP3);
            app.Label_16.Text = 'Agricultural practices includ sub-activities with agriculture BMPs including cover crops, mulching, reduced or no till, laser leveling, terraced/contour planting, agroforestry, regenerative agriculture, grazing management.';
            app.Label_17 = uilabel(app.AP3);
            app.Label_17.Text = 'Sub-activities';
            app.Label_18 = uilabel(app.AP3);
            app.Label_18.Text = 'Improved water quality through nonpoint source pollution reduction';
            app.Label_19 = uilabel(app.AP3);
            app.Label_19.Text = {'Volumetric '; 'objective '};
            app.Label_22 = uilabel(app.AP3);
            app.Label_22.Text = 'Nonpoint Source Pollutant Reduction Method';
            app.Label_23 = uilabel(app.AP3);
            app.Label_23.Text = 'The Nonpoint Source Pollutant Reduction Method enables estimation of the volumetric benefit of activities that improve water quality by avoiding or controlling nonpoint source pollutant loading originating from nonpoint sources.';
            app.Hyperlink_7 = uihyperlink(app.AP3);
            app.Hyperlink_7.URL = 'https://tnc.box.com/s/rot0b0c4wutwe12iq3l1biw2kb4h1jj8';
            app.Hyperlink_7.Text = 'Appendix A-13';
            app.Ouputs_AP3 = uipanel(app.AP3);
            app.Ouputs_AP3.Title = 'Ouputs';
            app.Processing_AP3 = uibutton(app.Ouputs_AP3, 'push');
            app.Processing_AP3.ButtonPushedFcn = createCallbackFcn(app, @Processing_AP3Pushed, true);
            app.Processing_AP3.Icon = 'Icons_Play.png';
            app.Processing_AP3.Text = 'Processing';
            app.VolumenrunoffLabel_2 = uilabel(app.Ouputs_AP3);
            app.VolumenrunoffLabel_2.Interpreter = 'latex';
            app.VolumenrunoffLabel_2.Text = '\bf VWB $\bf (m^{3}/yr)$';
            app.Index_AP3 = uieditfield(app.Ouputs_AP3, 'numeric');
            app.Index_AP3.Editable = 'off';
            app.GDB_AP3_VI = uibutton(app.AP3, 'push');
            app.GDB_AP3_VI.ButtonPushedFcn = createCallbackFcn(app, @GDB_AP3_VIPushed, true);
            app.GDB_AP3_VI.Icon = 'Icons_DataBase.png';
            app.GDB_AP3_VI.Text = {'Calculate from '; 'Global Database'};
            app.Param_AP3_NL = uieditfield(app.AP3, 'numeric');
            app.Param_AP3_NL.Limits = [1 Inf];
            app.Param_AP3_NL.Value = 1;
            app.Param_AP3_NA = uieditfield(app.AP3, 'numeric');
            app.Param_AP3_NA.Limits = [0 Inf];
            app.Param_AP3_TIN = uieditfield(app.AP3, 'numeric');
            app.Param_AP3_TIN.Limits = [1 Inf];
            app.Param_AP3_TIN.Value = 1;
            app.Param_AP3_IIA = uieditfield(app.AP3, 'numeric');
            app.Param_AP3_IIA.Limits = [0 Inf];
            app.CO2eWithActivitiesLabel_8 = uilabel(app.AP3);
            app.CO2eWithActivitiesLabel_8.Interpreter = 'latex';
            app.CO2eWithActivitiesLabel_8.Text = '\bf Post-Project Runoff $\bf (m3)$';
            app.Param_AP3_WAR = uieditfield(app.AP3, 'numeric');
            app.Param_AP3_WAR.Limits = [0 Inf];
            app.CO2eWithActivitiesLabel_9 = uilabel(app.AP3);
            app.CO2eWithActivitiesLabel_9.Interpreter = 'latex';
            app.CO2eWithActivitiesLabel_9.Text = {'\bf Incremental Improvement '; '\bf by Activity'};
            app.CO2eWithActivitiesLabel_10 = uilabel(app.AP3);
            app.CO2eWithActivitiesLabel_10.Interpreter = 'latex';
            app.CO2eWithActivitiesLabel_10.Text = {'\bf Number of WQ Challenges '; '\bf Addressed by Activity '};
            app.CO2eWithActivitiesLabel_11 = uilabel(app.AP3);
            app.CO2eWithActivitiesLabel_11.Interpreter = 'latex';
            app.CO2eWithActivitiesLabel_11.Text = {'\bf Total Improvement '; '\bf Needed '};
            app.CO2eWithActivitiesLabel_12 = uilabel(app.AP3);
            app.CO2eWithActivitiesLabel_12.Interpreter = 'latex';
            app.CO2eWithActivitiesLabel_12.Text = {'\bf Number of WQ Challenges '; '\bf Caused by Land Use'};
            app.Button_Porfolio = uibutton(app.AgriculturalpracticesTab, 'push');
            app.Button_Porfolio.ButtonPushedFcn = createCallbackFcn(app, @Button_PorfolioPushed, true);
            app.Button_Porfolio.Icon = 'Icons_Raster.png';
            app.Button_Porfolio.Text = {'Spatially distribute '; 'area and obtain raster'};
            app.Lamp_AgriPrac = uilamp(app.AgriculturalpracticesTab);
            app.AreahaEditFieldLabel = uilabel(app.AgriculturalpracticesTab);
            app.AreahaEditFieldLabel.Text = 'Area (ha)';
            app.Area_AgriPrac = uieditfield(app.AgriculturalpracticesTab, 'numeric');
            app.Area_AgriPrac.Limits = [0 Inf];
            app.DemandmanagementTab = uitab(app.TabGroup);
            app.DemandmanagementTab.Title = 'Demand management';
            app.Panel_DeManag = uipanel(app.DemandmanagementTab);
            app.TabGroup2_3 = uitabgroup(app.Panel_DeManag);
            app.VWBlegaltransactionsTab = uitab(app.TabGroup2_3);
            app.VWBlegaltransactionsTab.Title = 'VWB (legal transactions)';
            app.Label_94 = uilabel(app.VWBlegaltransactionsTab);
            app.Label_94.Text = 'Withdrawal and Consumption Methods ';
            app.Label_95 = uilabel(app.VWBlegaltransactionsTab);
            app.Label_95.Text = {'The Withdrawal and Consumption methods enable estimation of the volumetric benefit of activities that reduce water withdrawal, reduce non-revenue water or reduce consumption.'; ''; 'For legal transactions, the VWB can be determined based on the water rights leased or purchased and the duration, with the actual VWB occurring when the volume is available in-stream.  The diversion flow rate can vary over time. To account for this variability, a conservative estimate of diversion flows (i.e., diversion flows representative of dry period) should be used. When the objective is to restore streamflow in a dewatered reach or enhance streamflow for a targeted fish population, the period of diversion or flow rate may be narrowed to focus only on the period of ecological significance, such as the spawning period and/or the flow rate providing that benefit.'};
            app.Label_96 = uilabel(app.VWBlegaltransactionsTab);
            app.Label_96.Text = 'Legal transactions to keep water in-stream, operational efficiency measures, water reuse and recycling, changes in industrial processes or agricultural practices that reduce demand, changes in water sources, low flow fixtures, or other activities that reduce demand';
            app.Label_97 = uilabel(app.VWBlegaltransactionsTab);
            app.Label_97.Text = 'Sub-activities';
            app.Label_98 = uilabel(app.VWBlegaltransactionsTab);
            app.Label_98.Text = 'Improved water balance through reduced demand';
            app.Label_99 = uilabel(app.VWBlegaltransactionsTab);
            app.Label_99.Text = {'Volumetric '; 'objective '};
            app.Hyperlink_WCN1 = uihyperlink(app.VWBlegaltransactionsTab);
            app.Hyperlink_WCN1.URL = 'https://tnc.box.com/s/d646q87nj6q3w4kpbb86zmjhjk72wnhn';
            app.Hyperlink_WCN1.Text = 'Appendix A-2';
            app.OuputsPanel_13 = uipanel(app.VWBlegaltransactionsTab);
            app.OuputsPanel_13.Title = 'Ouputs';
            app.Reducedrunoffm3Label_6 = uilabel(app.OuputsPanel_13);
            app.Reducedrunoffm3Label_6.Interpreter = 'latex';
            app.Reducedrunoffm3Label_6.Text = '\bf VWB $\bf (m^{3})$';
            app.Index_WCN1 = uieditfield(app.OuputsPanel_13, 'numeric');
            app.Index_WCN1.Editable = 'off';
            app.Button_WCN1 = uibutton(app.VWBlegaltransactionsTab, 'push');
            app.Button_WCN1.ButtonPushedFcn = createCallbackFcn(app, @Button_WCN1Pushed, true);
            app.Button_WCN1.Icon = 'Icons_Play.png';
            app.Button_WCN1.Text = 'Processing';
            app.CurvenumberRasterLabel_6 = uilabel(app.VWBlegaltransactionsTab);
            app.CurvenumberRasterLabel_6.Text = {'Diversion flow rate '; 'reallocated for '; 'in-stream flow (l/s)'};
            app.CurvenumberWithActivitiesLabel_7 = uilabel(app.VWBlegaltransactionsTab);
            app.CurvenumberWithActivitiesLabel_7.Text = 'Duration (day)';
            app.WCN1_Param_2 = uieditfield(app.VWBlegaltransactionsTab, 'numeric');
            app.WCN1_Param_2.Limits = [1 Inf];
            app.WCN1_Param_2.ValueDisplayFormat = '%.0d';
            app.WCN1_Param_2.Value = 1;
            app.WCN1_Param_1 = uieditfield(app.VWBlegaltransactionsTab, 'numeric');
            app.WCN1_Param_1.Limits = [0 Inf];
            app.VWBlegaltransactionsandAgriculturalirrigationTab = uitab(app.TabGroup2_3);
            app.VWBlegaltransactionsandAgriculturalirrigationTab.Title = 'VWB - (Improve water use efficiency)';
            app.Label_37 = uilabel(app.VWBlegaltransactionsandAgriculturalirrigationTab);
            app.Label_37.Text = 'Withdrawal and Consumption Methods ';
            app.Label_38 = uilabel(app.VWBlegaltransactionsandAgriculturalirrigationTab);
            app.Label_38.Text = {'The Withdrawal and Consumption methods enable estimation of the volumetric benefit of activities that reduce water withdrawal, reduce non-revenue water or reduce consumption.'; ''; 'For measures that improve water use efficiency or repair leaks, baseline and with-project withdrawal volumes may be obtained from the water provider or facility/home water bills (reduced withdrawal for consumer use efficiency measures, such as installing low-flow showerheads, can also be based on the number of showerheads installed, the flow rate of the old and newer more efficient showerheads, and assumptions regarding daily shower usage). For water reuse projects, the reduced withdrawal volume can be calculated based on the volume reused (e.g., the volume of treated effluent used for landscaping in place of potable water).'};
            app.CurvenumberRasterLabel_3 = uilabel(app.VWBlegaltransactionsandAgriculturalirrigationTab);
            app.CurvenumberRasterLabel_3.Text = {'Withdrawal volume -'; 'without project (l/s)'};
            app.CurvenumberWithActivitiesLabel_3 = uilabel(app.VWBlegaltransactionsandAgriculturalirrigationTab);
            app.CurvenumberWithActivitiesLabel_3.Text = 'Duration project (day)';
            app.Label_39 = uilabel(app.VWBlegaltransactionsandAgriculturalirrigationTab);
            app.Label_39.Text = 'Legal transactions to keep water in-stream, operational efficiency measures, water reuse and recycling, changes in industrial processes or agricultural practices that reduce demand, changes in water sources, low flow fixtures, or other activities that reduce demand';
            app.Label_40 = uilabel(app.VWBlegaltransactionsandAgriculturalirrigationTab);
            app.Label_40.Text = 'Sub-activities';
            app.Label_41 = uilabel(app.VWBlegaltransactionsandAgriculturalirrigationTab);
            app.Label_41.Text = 'Improved water balance through reduced demand';
            app.Label_42 = uilabel(app.VWBlegaltransactionsandAgriculturalirrigationTab);
            app.Label_42.Text = {'Volumetric '; 'objective '};
            app.Hyperlink_WCN2 = uihyperlink(app.VWBlegaltransactionsandAgriculturalirrigationTab);
            app.Hyperlink_WCN2.URL = 'https://tnc.box.com/s/d646q87nj6q3w4kpbb86zmjhjk72wnhn';
            app.Hyperlink_WCN2.Text = 'Appendix A-2';
            app.OuputsPanel_14 = uipanel(app.VWBlegaltransactionsandAgriculturalirrigationTab);
            app.OuputsPanel_14.Title = 'Ouputs';
            app.Reducedrunoffm3Label_7 = uilabel(app.OuputsPanel_14);
            app.Reducedrunoffm3Label_7.Interpreter = 'latex';
            app.Reducedrunoffm3Label_7.Text = '\bf VWB $\bf (m^{3})$';
            app.Index_WCN2 = uieditfield(app.OuputsPanel_14, 'numeric');
            app.Index_WCN2.Editable = 'off';
            app.Button_WCN2 = uibutton(app.OuputsPanel_14, 'push');
            app.Button_WCN2.ButtonPushedFcn = createCallbackFcn(app, @Button_WCN2Pushed, true);
            app.Button_WCN2.Icon = 'Icons_Play.png';
            app.Button_WCN2.Text = 'Processing';
            app.WCN2_Param_3 = uieditfield(app.VWBlegaltransactionsandAgriculturalirrigationTab, 'numeric');
            app.WCN2_Param_3.Limits = [1 Inf];
            app.WCN2_Param_3.ValueDisplayFormat = '%.1d';
            app.WCN2_Param_3.Value = 1;
            app.CurvenumberRasterLabel_7 = uilabel(app.VWBlegaltransactionsandAgriculturalirrigationTab);
            app.CurvenumberRasterLabel_7.Text = {'Withdrawal volume -'; 'with project (l/s)'};
            app.WCN2_Param_2 = uieditfield(app.VWBlegaltransactionsandAgriculturalirrigationTab, 'numeric');
            app.WCN2_Param_2.Limits = [0 Inf];
            app.WCN2_Param_1 = uieditfield(app.VWBlegaltransactionsandAgriculturalirrigationTab, 'numeric');
            app.WCN2_Param_1.Limits = [0 Inf];
            app.VWBNonrevenueWaterTab = uitab(app.TabGroup2_3);
            app.VWBNonrevenueWaterTab.Title = 'VWB (Non-revenue Water) ';
            app.Label_106 = uilabel(app.VWBNonrevenueWaterTab);
            app.Label_106.Text = 'Withdrawal and Consumption Methods ';
            app.Label_107 = uilabel(app.VWBNonrevenueWaterTab);
            app.Label_107.Text = {'The Withdrawal and Consumption methods enable estimation of the volumetric benefit of activities that reduce water withdrawal, reduce non-revenue water or reduce consumption.'; ''; 'For leak detection and repair projects that reduce Non-revenue Water (NRW) loss within water utilities, baseline and with-project volumes should be based on the reduction of NRW. NRW is water that has been produced by the utility but is subsequently lost before it reaches the customer. Water utilities can incur NRW due to system leakage, aging infrastructure, etc., straining the water supply source. NRW is one of the more persistent problems in municipal water systems, and high levels of NRW are detrimental to the financial viability of water utilities.  When information is available for NRW, the NRW reduction is calculated as the difference in NRW between the “baseline” (i.e., without-project) and the with-project conditions.'};
            app.Label_108 = uilabel(app.VWBNonrevenueWaterTab);
            app.Label_108.Text = 'Legal transactions to keep water in-stream, operational efficiency measures, water reuse and recycling, changes in industrial processes or agricultural practices that reduce demand, changes in water sources, low flow fixtures, or other activities that reduce demand';
            app.Label_109 = uilabel(app.VWBNonrevenueWaterTab);
            app.Label_109.Text = 'Sub-activities';
            app.Label_110 = uilabel(app.VWBNonrevenueWaterTab);
            app.Label_110.Text = 'Improved water balance through reduced demand';
            app.Label_111 = uilabel(app.VWBNonrevenueWaterTab);
            app.Label_111.Text = {'Volumetric '; 'objective '};
            app.Hyperlink_WCN3 = uihyperlink(app.VWBNonrevenueWaterTab);
            app.Hyperlink_WCN3.URL = 'https://tnc.box.com/s/d646q87nj6q3w4kpbb86zmjhjk72wnhn';
            app.Hyperlink_WCN3.Text = 'Appendix A-2';
            app.OuputsPanel_16 = uipanel(app.VWBNonrevenueWaterTab);
            app.OuputsPanel_16.Title = 'Ouputs';
            app.Reducedrunoffm3Label_9 = uilabel(app.OuputsPanel_16);
            app.Reducedrunoffm3Label_9.Interpreter = 'latex';
            app.Reducedrunoffm3Label_9.Text = '\bf VWB $\bf (m^{3})$';
            app.Index_WCN3 = uieditfield(app.OuputsPanel_16, 'numeric');
            app.Index_WCN3.Editable = 'off';
            app.Button_WCN3 = uibutton(app.OuputsPanel_16, 'push');
            app.Button_WCN3.ButtonPushedFcn = createCallbackFcn(app, @Button_WCN3Pushed, true);
            app.Button_WCN3.Icon = 'Icons_Play.png';
            app.Button_WCN3.Text = 'Processing';
            app.WCN3_Param_3 = uieditfield(app.VWBNonrevenueWaterTab, 'numeric');
            app.WCN3_Param_3.Limits = [1 Inf];
            app.WCN3_Param_3.ValueDisplayFormat = '%.0d';
            app.WCN3_Param_3.Value = 1;
            app.WCN3_Param_2 = uieditfield(app.VWBNonrevenueWaterTab, 'numeric');
            app.WCN3_Param_2.Limits = [0 Inf];
            app.CurvenumberRasterLabel_10 = uilabel(app.VWBNonrevenueWaterTab);
            app.CurvenumberRasterLabel_10.Text = {'Withdrawal volume - '; 'without project (l/s)'};
            app.WCN3_Param_1 = uieditfield(app.VWBNonrevenueWaterTab, 'numeric');
            app.WCN3_Param_1.Limits = [0 Inf];
            app.CurvenumberRasterLabel_11 = uilabel(app.VWBNonrevenueWaterTab);
            app.CurvenumberRasterLabel_11.Text = {'Withdrawal volume - '; 'with project (l/s)'};
            app.CurvenumberWithActivitiesLabel_13 = uilabel(app.VWBNonrevenueWaterTab);
            app.CurvenumberWithActivitiesLabel_13.Text = {'Duration '; 'project (day)'};
            app.VWBAgriculturalirrigationefficiencyTab = uitab(app.TabGroup2_3);
            app.VWBAgriculturalirrigationefficiencyTab.Title = 'VWB - (Agricultural irrigation efficiency)';
            app.Label_100 = uilabel(app.VWBAgriculturalirrigationefficiencyTab);
            app.Label_100.Text = 'Withdrawal and Consumption Methods ';
            app.Label_101 = uilabel(app.VWBAgriculturalirrigationefficiencyTab);
            app.Label_101.Text = {'The Withdrawal and Consumption methods enable estimation of the volumetric benefit of activities that reduce water withdrawal, reduce non-revenue water or reduce consumption.'; ''; 'Agricultural irrigation efficiency measures can reduce consumption by converting from less efficient irrigation methods, such as flood irrigation, to more efficient irrigation methods, such as drip irrigation, or by lining or piping conveyance ditches to reduce transmission loss. Consumption is estimated based on withdrawal volume and adjusted to account for return flow fraction. Return flow fraction, expressed as a percentage, is the fraction of the withdrawal volume that is not consumed and is returned to the source. The reduced consumption volume is calculated as the difference in consumption volume between the “with-project” and “baseline” conditions.'};
            app.Label_102 = uilabel(app.VWBAgriculturalirrigationefficiencyTab);
            app.Label_102.Text = 'Legal transactions to keep water in-stream, operational efficiency measures, water reuse and recycling, changes in industrial processes or agricultural practices that reduce demand, changes in water sources, low flow fixtures, or other activities that reduce demand';
            app.Label_103 = uilabel(app.VWBAgriculturalirrigationefficiencyTab);
            app.Label_103.Text = 'Sub-activities';
            app.Label_104 = uilabel(app.VWBAgriculturalirrigationefficiencyTab);
            app.Label_104.Text = 'Improved water balance through reduced demand';
            app.Label_105 = uilabel(app.VWBAgriculturalirrigationefficiencyTab);
            app.Label_105.Text = {'Volumetric '; 'objective '};
            app.Hyperlink_WCN4 = uihyperlink(app.VWBAgriculturalirrigationefficiencyTab);
            app.Hyperlink_WCN4.URL = 'https://tnc.box.com/s/d646q87nj6q3w4kpbb86zmjhjk72wnhn';
            app.Hyperlink_WCN4.Text = 'Appendix A-2';
            app.OuputsPanel_15 = uipanel(app.VWBAgriculturalirrigationefficiencyTab);
            app.OuputsPanel_15.Title = 'Ouputs';
            app.Reducedrunoffm3Label_8 = uilabel(app.OuputsPanel_15);
            app.Reducedrunoffm3Label_8.Interpreter = 'latex';
            app.Reducedrunoffm3Label_8.Text = '\bf VWB $\bf (m^{3})$';
            app.Index_WCN4 = uieditfield(app.OuputsPanel_15, 'numeric');
            app.Index_WCN4.Editable = 'off';
            app.Button_WCN4 = uibutton(app.OuputsPanel_15, 'push');
            app.Button_WCN4.ButtonPushedFcn = createCallbackFcn(app, @Button_WCN4Pushed, true);
            app.Button_WCN4.Icon = 'Icons_Play.png';
            app.Button_WCN4.Text = 'Processing';
            app.CurvenumberRasterLabel_8 = uilabel(app.VWBAgriculturalirrigationefficiencyTab);
            app.CurvenumberRasterLabel_8.Text = {'Withdrawal volume - '; 'without project (l/s)'};
            app.WCN4_Param_4 = uieditfield(app.VWBAgriculturalirrigationefficiencyTab, 'numeric');
            app.WCN4_Param_4.Limits = [0 1];
            app.CurvenumberWithActivitiesLabel_8 = uilabel(app.VWBAgriculturalirrigationefficiencyTab);
            app.CurvenumberWithActivitiesLabel_8.Text = {'Duration of '; 'diversion (day)'};
            app.WCN4_Param_3 = uieditfield(app.VWBAgriculturalirrigationefficiencyTab, 'numeric');
            app.WCN4_Param_3.Limits = [1 Inf];
            app.WCN4_Param_3.ValueDisplayFormat = '%.0d';
            app.WCN4_Param_3.Value = 1;
            app.CurvenumberWithActivitiesLabel_9 = uilabel(app.VWBAgriculturalirrigationefficiencyTab);
            app.CurvenumberWithActivitiesLabel_9.Text = {'Return Flow '; 'Fraction (%)'};
            app.CurvenumberRasterLabel_9 = uilabel(app.VWBAgriculturalirrigationefficiencyTab);
            app.CurvenumberRasterLabel_9.Text = {'Withdrawal volume - '; 'with project (l/s)'};
            app.WCN4_Param_2 = uieditfield(app.VWBAgriculturalirrigationefficiencyTab, 'numeric');
            app.WCN4_Param_2.Limits = [0 Inf];
            app.WCN4_Param_1 = uieditfield(app.VWBAgriculturalirrigationefficiencyTab, 'numeric');
            app.WCN4_Param_1.Limits = [0 Inf];
            app.VWBLandcoverrestorationTab = uitab(app.TabGroup2_3);
            app.VWBLandcoverrestorationTab.Title = 'VWB (Land cover restoration)';
            app.Label_112 = uilabel(app.VWBLandcoverrestorationTab);
            app.Label_112.Text = 'Withdrawal and Consumption Methods ';
            app.Label_113 = uilabel(app.VWBLandcoverrestorationTab);
            app.Label_113.Text = {'The Withdrawal and Consumption methods enable estimation of the volumetric benefit of activities that reduce water withdrawal, reduce non-revenue water or reduce consumption.'; ''; 'Land cover restoration and the associated vegetation changes can affect the water recycling patterns and consumptive use by altering evapotranspiration (ET). Certain landcover restoration activities such as the removal of water thirsty non-native or invasive vegetation species and forest fire management through thinning treatment can reduce the consumptive use thereby increasing local water availability.  Crop conversion can also reduce consumption when crops with a higher evapotranspiration rate are replaced with crops having a lower evapotranspiration rate.'};
            app.Label_114 = uilabel(app.VWBLandcoverrestorationTab);
            app.Label_114.Text = 'Legal transactions to keep water in-stream, operational efficiency measures, water reuse and recycling, changes in industrial processes or agricultural practices that reduce demand, changes in water sources, low flow fixtures, or other activities that reduce demand';
            app.Label_115 = uilabel(app.VWBLandcoverrestorationTab);
            app.Label_115.Text = 'Sub-activities';
            app.Label_116 = uilabel(app.VWBLandcoverrestorationTab);
            app.Label_116.Text = 'Improved water balance through reduced demand';
            app.Label_117 = uilabel(app.VWBLandcoverrestorationTab);
            app.Label_117.Text = {'Volumetric '; 'objective '};
            app.Hyperlink_11 = uihyperlink(app.VWBLandcoverrestorationTab);
            app.Hyperlink_11.URL = 'https://tnc.box.com/s/d646q87nj6q3w4kpbb86zmjhjk72wnhn';
            app.Hyperlink_11.Text = 'Appendix A-2';
            app.OuputsPanel_17 = uipanel(app.VWBLandcoverrestorationTab);
            app.OuputsPanel_17.Title = 'Ouputs';
            app.Reducedrunoffm3Label_10 = uilabel(app.OuputsPanel_17);
            app.Reducedrunoffm3Label_10.Interpreter = 'latex';
            app.Reducedrunoffm3Label_10.Text = '\bf VWB $\bf (m^{3}/yr)$';
            app.Index_WCN5 = uieditfield(app.OuputsPanel_17, 'numeric');
            app.Index_WCN5.Editable = 'off';
            app.Button_WCN5 = uibutton(app.OuputsPanel_17, 'push');
            app.Button_WCN5.ButtonPushedFcn = createCallbackFcn(app, @Button_WCN5Pushed, true);
            app.Button_WCN5.Icon = 'Icons_Play.png';
            app.Button_WCN5.Text = 'Processing';
            app.WCN5_Param_4 = uieditfield(app.VWBLandcoverrestorationTab, 'numeric');
            app.WCN5_Param_4.Limits = [0 1];
            app.WCN5_Param_3 = uieditfield(app.VWBLandcoverrestorationTab, 'numeric');
            app.WCN5_Param_3.Limits = [0 Inf];
            app.CurvenumberWithActivitiesLabel_10 = uilabel(app.VWBLandcoverrestorationTab);
            app.CurvenumberWithActivitiesLabel_10.Text = {'Evapotranspiration - '; 'with activities (mm)'};
            app.WCN5_Param_2 = uieditfield(app.VWBLandcoverrestorationTab, 'numeric');
            app.WCN5_Param_2.Limits = [0 Inf];
            app.CurvenumberRasterLabel_13 = uilabel(app.VWBLandcoverrestorationTab);
            app.CurvenumberRasterLabel_13.Text = {'Evapotranspiration -'; 'without activities (mm)'};
            app.WCN5_Param_1 = uieditfield(app.VWBLandcoverrestorationTab, 'numeric');
            app.WCN5_Param_1.Limits = [0 Inf];
            app.CurvenumberWithActivitiesLabel_11 = uilabel(app.VWBLandcoverrestorationTab);
            app.CurvenumberWithActivitiesLabel_11.Text = 'Invasive species cover (%)';
            app.WCN5_GDB_3 = uibutton(app.VWBLandcoverrestorationTab, 'push');
            app.WCN5_GDB_3.ButtonPushedFcn = createCallbackFcn(app, @WCN5_GDB_3ButtonPushed, true);
            app.WCN5_GDB_3.Icon = 'Icons_DataBase.png';
            app.WCN5_GDB_3.Text = {'Calculate from '; 'Global Database'};
            app.WCN5_GDB_4 = uibutton(app.VWBLandcoverrestorationTab, 'push');
            app.WCN5_GDB_4.ButtonPushedFcn = createCallbackFcn(app, @WCN5_GDB_4ButtonPushed, true);
            app.WCN5_GDB_4.Icon = 'Icons_DataBase.png';
            app.WCN5_GDB_4.Text = {'Calculate from '; 'Global Database'};
            app.CurvenumberRasterLabel_12 = uilabel(app.VWBLandcoverrestorationTab);
            app.CurvenumberRasterLabel_12.Text = {'Area '; 'Affected (ha)'};
            app.Label_55 = uilabel(app.DemandmanagementTab);
            app.Label_55.Text = 'Demand management includes actions such as legal transactions to keep water in the stream, operational efficiency measures, water reuse and recycling, changes in industrial processes or agricultural practices that reduce demand, changes in water sources, low-flow fixtures, or other activities that reduce demand.';
            app.GreenandgrayinfrastructureTab = uitab(app.TabGroup);
            app.GreenandgrayinfrastructureTab.Title = 'Green and gray infrastructure';
            app.Panel_GreenInfra = uipanel(app.GreenandgrayinfrastructureTab);
            app.TabGroup2_4 = uitabgroup(app.Panel_GreenInfra);
            app.VolumecapturedTab_3 = uitab(app.TabGroup2_4);
            app.VolumecapturedTab_3.Title = 'Volume captured';
            app.Label_142 = uilabel(app.VolumecapturedTab_3);
            app.Label_142.Text = 'Rain gardens, bioswales, stormwater detention or retention ponds, pond dredging/desilting, drainage water management, blind inlets, and others designed to capture runoff ';
            app.Label_143 = uilabel(app.VolumecapturedTab_3);
            app.Label_143.Text = 'Sub-activities';
            app.Label_144 = uilabel(app.VolumecapturedTab_3);
            app.Label_144.Text = 'Improved water quality through nonpoint source pollution reduction and Improved water-related habitat';
            app.Label_145 = uilabel(app.VolumecapturedTab_3);
            app.Label_145.Text = {'Volumetric '; 'objective '};
            app.Label_146 = uilabel(app.VolumecapturedTab_3);
            app.Label_146.Text = 'Volume Provided Method';
            app.Label_147 = uilabel(app.VolumecapturedTab_3);
            app.Label_147.Text = 'The Volume Captured method enables volumetric benefit estimation of activities that create, restore or protect a volume captured and/or stored';
            app.Button_GDB_GG1 = uibutton(app.VolumecapturedTab_3, 'push');
            app.Button_GDB_GG1.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_GG1Pushed, true);
            app.Button_GDB_GG1.Icon = 'Icons_DataBase.png';
            app.Button_GDB_GG1.Text = {'Calculate from '; 'Global Database'};
            app.OuputsPanel_22 = uipanel(app.VolumecapturedTab_3);
            app.OuputsPanel_22.Title = 'Ouputs';
            app.Button_GGI1 = uibutton(app.OuputsPanel_22, 'push');
            app.Button_GGI1.ButtonPushedFcn = createCallbackFcn(app, @Button_GGI1Pushed2, true);
            app.Button_GGI1.Icon = 'Icons_Play.png';
            app.Button_GGI1.Text = 'Processing';
            app.VolumenrunoffLabel_11 = uilabel(app.OuputsPanel_22);
            app.VolumenrunoffLabel_11.Interpreter = 'latex';
            app.VolumenrunoffLabel_11.Text = '\bf VWB $\bf (m^{3}/yr)$';
            app.Index_GGI1 = uieditfield(app.OuputsPanel_22, 'numeric');
            app.Index_GGI1.Editable = 'off';
            app.Hyperlink_17 = uihyperlink(app.VolumecapturedTab_3);
            app.Hyperlink_17.URL = 'https://tnc.box.com/s/j1e5ouotnrb267suh4k7v2odp2nc00vr';
            app.Hyperlink_17.Text = 'Appendix A-5';
            app.CO2eWithActivitiesLabel_55 = uilabel(app.VolumecapturedTab_3);
            app.CO2eWithActivitiesLabel_55.Text = {'Runoff reduction'; 'factor (Ad)'};
            app.GGI1_Param_4 = uieditfield(app.VolumecapturedTab_3, 'numeric');
            app.GGI1_Param_4.Limits = [0 1];
            app.CO2eWithActivitiesLabel_54 = uilabel(app.VolumecapturedTab_3);
            app.CO2eWithActivitiesLabel_54.Text = {'Runoff '; 'coefficient (Ad)'};
            app.GGI1_Param_3 = uieditfield(app.VolumecapturedTab_3, 'numeric');
            app.GGI1_Param_3.Limits = [0 1];
            app.GGI1_Param_2 = uieditfield(app.VolumecapturedTab_3, 'numeric');
            app.GGI1_Param_2.Limits = [0 Inf];
            app.CO2eWithActivitiesLabel_52 = uilabel(app.VolumecapturedTab_3);
            app.CO2eWithActivitiesLabel_52.Text = 'Surface area (ha)';
            app.CO2eWithActivitiesLabel_53 = uilabel(app.VolumecapturedTab_3);
            app.CO2eWithActivitiesLabel_53.Text = {'Annual average '; 'rainfall (mm)'};
            app.GGI1_Param_1 = uieditfield(app.VolumecapturedTab_3, 'numeric');
            app.GGI1_Param_1.Limits = [0 Inf];
            app.VolumetreatedTab_2 = uitab(app.TabGroup2_4);
            app.VolumetreatedTab_2.Title = 'Volume treated';
            app.Label_118 = uilabel(app.VolumetreatedTab_2);
            app.Label_118.Text = 'Volume Treated Method';
            app.Label_119 = uilabel(app.VolumetreatedTab_2);
            app.Label_119.Text = 'The Volume Treated method enables estimation of the volumetric benefit of activities that have the primary objective of improving water quality (WQ). ';
            app.Label_120 = uilabel(app.VolumetreatedTab_2);
            app.Label_120.Text = 'Green infrastructure activities including constructed wetland treatment system, bioretention basins, and others';
            app.Label_121 = uilabel(app.VolumetreatedTab_2);
            app.Label_121.Text = 'Sub-activities';
            app.Label_122 = uilabel(app.VolumetreatedTab_2);
            app.Label_122.Text = 'Improved water quality through nonpoint source pollution reduction and Improved water balance through flood/drought mitigation';
            app.Label_123 = uilabel(app.VolumetreatedTab_2);
            app.Label_123.Text = {'Volumetric '; 'objective '};
            app.OuputsPanel_18 = uipanel(app.VolumetreatedTab_2);
            app.OuputsPanel_18.Title = 'Ouputs';
            app.Button_Processing_Ax1_11 = uibutton(app.OuputsPanel_18, 'push');
            app.Button_Processing_Ax1_11.ButtonPushedFcn = createCallbackFcn(app, @Button_Processing_Ax1_11Pushed, true);
            app.Button_Processing_Ax1_11.Icon = 'Icons_Play.png';
            app.Button_Processing_Ax1_11.Text = 'Processing';
            app.Reducedrunoffm3Label_11 = uilabel(app.OuputsPanel_18);
            app.Reducedrunoffm3Label_11.Interpreter = 'latex';
            app.Reducedrunoffm3Label_11.Text = '\bf VWB $\bf (m^{3})$';
            app.Index_GGI2 = uieditfield(app.OuputsPanel_18, 'numeric');
            app.Index_GGI2.Editable = 'off';
            app.Hyperlink_12 = uihyperlink(app.VolumetreatedTab_2);
            app.Hyperlink_12.URL = 'https://tnc.box.com/s/b6bmorp3jnaapx6rn3toxhmd4rgme4w1';
            app.Hyperlink_12.Text = 'Appendix A-6';
            app.CurvenumberRasterLabel_15 = uilabel(app.VolumetreatedTab_2);
            app.CurvenumberRasterLabel_15.Interpreter = 'latex';
            app.CurvenumberRasterLabel_15.Text = {'\bf Annual volume of '; '\bf water treated $\bf (m^3)$'};
            app.GGI2_Param_1 = uieditfield(app.VolumetreatedTab_2, 'numeric');
            app.GGI2_Param_1.Limits = [0 Inf];
            app.GGI2_Table = uitable(app.VolumetreatedTab_2);
            app.GGI2_Table.ColumnName = {'Challenges'; 'Fraction Improved (%)'};
            app.GGI2_Table.RowName = {};
            app.GGI2_Table.ColumnEditable = true;
            app.ChallengesSpinnerLabel = uilabel(app.VolumetreatedTab_2);
            app.ChallengesSpinnerLabel.Text = 'Challenges';
            app.ChallengesSpinner = uispinner(app.VolumetreatedTab_2);
            app.ChallengesSpinner.Limits = [1 10];
            app.ChallengesSpinner.ValueChangedFcn = createCallbackFcn(app, @ChallengesSpinnerValueChanged, true);
            app.ChallengesSpinner.Value = 1;
            app.VolumeprovidedTab_2 = uitab(app.TabGroup2_4);
            app.VolumeprovidedTab_2.Title = 'Volume provided';
            app.TabGroup6 = uitabgroup(app.VolumeprovidedTab_2);
            app.VolumeProvidedMethodTab = uitab(app.TabGroup6);
            app.VolumeProvidedMethodTab.Title = 'Volume Provided Method';
            app.Label_68 = uilabel(app.VolumeProvidedMethodTab);
            app.Label_68.Text = 'New water supply for irrigation or domestic use (including handwashing, bathing and cleaning), water reuse, water collection and storage, water treatment, rain barrels and others that provide an increased water supply ';
            app.Label_69 = uilabel(app.VolumeProvidedMethodTab);
            app.Label_69.Text = 'Sub-activities';
            app.Label_70 = uilabel(app.VolumeProvidedMethodTab);
            app.Label_70.Text = 'Improved water balance through increased supply';
            app.Label_71 = uilabel(app.VolumeProvidedMethodTab);
            app.Label_71.Text = {'Volumetric '; 'objective '};
            app.Label_72 = uilabel(app.VolumeProvidedMethodTab);
            app.Label_72.Text = 'Volume Provided Method';
            app.Label_73 = uilabel(app.VolumeProvidedMethodTab);
            app.Label_73.Text = 'The Volume Provided method enables estimation of the volumetric benefit of activities that provide a volume of water that contributes to improving human health and/or livelihood, as well as social or economic security';
            app.Button_GDB_RPerCapita = uibutton(app.VolumeProvidedMethodTab, 'push');
            app.Button_GDB_RPerCapita.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_RPerCapitaPushed, true);
            app.Button_GDB_RPerCapita.Icon = 'Icons_DataBase.png';
            app.Button_GDB_RPerCapita.Text = {'Calculate from '; 'Global Database'};
            app.OuputsPanel_9 = uipanel(app.VolumeProvidedMethodTab);
            app.OuputsPanel_9.Title = 'Ouputs';
            app.Button_GGI3 = uibutton(app.OuputsPanel_9, 'push');
            app.Button_GGI3.ButtonPushedFcn = createCallbackFcn(app, @Button_GGI3Pushed, true);
            app.Button_GGI3.Icon = 'Icons_Play.png';
            app.Button_GGI3.Text = 'Processing';
            app.VolumenrunoffLabel_5 = uilabel(app.OuputsPanel_9);
            app.VolumenrunoffLabel_5.Interpreter = 'latex';
            app.VolumenrunoffLabel_5.Text = '\bf VWB $\bf (m^{3})$';
            app.Index_GGI3 = uieditfield(app.OuputsPanel_9, 'numeric');
            app.Index_GGI3.Editable = 'off';
            app.Hyperlink_13 = uihyperlink(app.VolumeProvidedMethodTab);
            app.Hyperlink_13.URL = 'https://tnc.box.com/s/cyj5vjbmq1o4ayhl053lyqy0uemjnjd5';
            app.Hyperlink_13.Text = 'Appendix A-3';
            app.CO2eWithActivitiesLabel_45 = uilabel(app.VolumeProvidedMethodTab);
            app.CO2eWithActivitiesLabel_45.Text = {'Project '; 'duration (day)'};
            app.GGI3_Param_3 = uieditfield(app.VolumeProvidedMethodTab, 'numeric');
            app.GGI3_Param_3.Limits = [1 Inf];
            app.GGI3_Param_3.ValueDisplayFormat = '%.d';
            app.GGI3_Param_3.Value = 1;
            app.GGI3_Param_2 = uieditfield(app.VolumeProvidedMethodTab, 'numeric');
            app.GGI3_Param_2.Limits = [0 Inf];
            app.CO2eWithActivitiesLabel_31 = uilabel(app.VolumeProvidedMethodTab);
            app.CO2eWithActivitiesLabel_31.Text = {'Required per-capita '; 'availability (L/capita/day)'};
            app.CO2eWithActivitiesLabel_32 = uilabel(app.VolumeProvidedMethodTab);
            app.CO2eWithActivitiesLabel_32.Text = {'Number of '; 'beneficiaries '};
            app.GGI3_Param_1 = uieditfield(app.VolumeProvidedMethodTab, 'numeric');
            app.GGI3_Param_1.Limits = [1 Inf];
            app.GGI3_Param_1.ValueDisplayFormat = '%.d';
            app.GGI3_Param_1.Value = 1;
            app.HabitatvolumemethodTab = uitab(app.TabGroup6);
            app.HabitatvolumemethodTab.Title = 'Habitat volume method';
            app.Label_148 = uilabel(app.HabitatvolumemethodTab);
            app.Label_148.Text = 'New water supply for irrigation or domestic use (including handwashing, bathing and cleaning), water reuse, water collection and storage, water treatment, rain barrels and others that provide an increased water supply ';
            app.Label_149 = uilabel(app.HabitatvolumemethodTab);
            app.Label_149.Text = 'Sub-activities';
            app.Label_150 = uilabel(app.HabitatvolumemethodTab);
            app.Label_150.Text = 'Improved water balance through increased supply';
            app.Label_151 = uilabel(app.HabitatvolumemethodTab);
            app.Label_151.Text = {'Volumetric '; 'objective '};
            app.Label_152 = uilabel(app.HabitatvolumemethodTab);
            app.Label_152.Text = 'Habitat Volume Method';
            app.Label_153 = uilabel(app.HabitatvolumemethodTab);
            app.Label_153.Text = 'The Habitat Volume method enables volumetric benefit estimation for activities with the primary objective of creating, restoring or protecting a volume of water for aquatic habitat. These activities can also provide water quality and fire resilience benefits, among others.';
            app.OuputsPanel_23 = uipanel(app.HabitatvolumemethodTab);
            app.OuputsPanel_23.Title = 'Ouputs';
            app.Button_GGI4 = uibutton(app.OuputsPanel_23, 'push');
            app.Button_GGI4.ButtonPushedFcn = createCallbackFcn(app, @Button_GGI4Pushed, true);
            app.Button_GGI4.Icon = 'Icons_Play.png';
            app.Button_GGI4.Text = 'Processing';
            app.VolumenrunoffLabel_12 = uilabel(app.OuputsPanel_23);
            app.VolumenrunoffLabel_12.Interpreter = 'latex';
            app.VolumenrunoffLabel_12.Text = '\bf VWB $\bf (m^{3})$';
            app.Index_GGI4 = uieditfield(app.OuputsPanel_23, 'numeric');
            app.Index_GGI4.Editable = 'off';
            app.Hyperlink_18 = uihyperlink(app.HabitatvolumemethodTab);
            app.Hyperlink_18.URL = 'https://tnc.box.com/s/jai2jsxlyznsocpce2epeqqtj1m198ri';
            app.Hyperlink_18.Text = 'Appendix A-12';
            app.CO2eWithActivitiesLabel_58 = uilabel(app.HabitatvolumemethodTab);
            app.CO2eWithActivitiesLabel_58.Text = {'Minimum flow during period of ecological '; 'significance for target - Without Project (l/s)'};
            app.CO2eWithActivitiesLabel_59 = uilabel(app.HabitatvolumemethodTab);
            app.CO2eWithActivitiesLabel_59.Text = {'Project '; 'duration (day)'};
            app.GGI4_Param_3 = uieditfield(app.HabitatvolumemethodTab, 'numeric');
            app.GGI4_Param_3.Limits = [1 Inf];
            app.GGI4_Param_3.ValueDisplayFormat = '%.d';
            app.GGI4_Param_3.Value = 1;
            app.CO2eWithActivitiesLabel_60 = uilabel(app.HabitatvolumemethodTab);
            app.CO2eWithActivitiesLabel_60.Text = {'Minimum flow during period of ecological '; 'significance for target - With Project (l/s)'};
            app.GGI4_Param_2 = uieditfield(app.HabitatvolumemethodTab, 'numeric');
            app.GGI4_Param_2.Limits = [0 Inf];
            app.GGI4_Param_1 = uieditfield(app.HabitatvolumemethodTab, 'numeric');
            app.GGI4_Param_1.Limits = [0 Inf];
            app.IncreasedormaintainedinundationTab = uitab(app.TabGroup2_4);
            app.IncreasedormaintainedinundationTab.Title = 'Increased or maintained inundation';
            app.Label_136 = uilabel(app.IncreasedormaintainedinundationTab);
            app.Label_136.Text = 'In-stream barrier removal, dam reoperation, floodplain reconnection , levee or berm removal, side channel reconnection, riparian habitat improvements, process-based restoration, wet meadow restoration, beaver dam analogs, water level management for habitat, wetland or peat bog protection or restoration, wetland creation and others';
            app.Label_137 = uilabel(app.IncreasedormaintainedinundationTab);
            app.Label_137.Text = 'Sub-activities';
            app.Label_138 = uilabel(app.IncreasedormaintainedinundationTab);
            app.Label_138.Text = 'Improved water-related habitat';
            app.Label_139 = uilabel(app.IncreasedormaintainedinundationTab);
            app.Label_139.Text = {'Volumetric '; 'objective '};
            app.Label_140 = uilabel(app.IncreasedormaintainedinundationTab);
            app.Label_140.Text = 'Inundation Method ';
            app.Label_141 = uilabel(app.IncreasedormaintainedinundationTab);
            app.Label_141.Text = 'The Inundation method enables volumetric benefit estimation for activities that create, restore or protect areas where inundation is the primary hydrologic function provided or protected. ';
            app.OuputsPanel_21 = uipanel(app.IncreasedormaintainedinundationTab);
            app.OuputsPanel_21.Title = 'Ouputs';
            app.Button_GGI5 = uibutton(app.OuputsPanel_21, 'push');
            app.Button_GGI5.ButtonPushedFcn = createCallbackFcn(app, @Button_GGI5Pushed, true);
            app.Button_GGI5.Icon = 'Icons_Play.png';
            app.Button_GGI5.Text = 'Processing';
            app.VolumenrunoffLabel_10 = uilabel(app.OuputsPanel_21);
            app.VolumenrunoffLabel_10.Interpreter = 'latex';
            app.VolumenrunoffLabel_10.Text = '\bf VWB $\bf (m^{3}/yr)$';
            app.Index_GGI5 = uieditfield(app.OuputsPanel_21, 'numeric');
            app.Index_GGI5.Editable = 'off';
            app.Hyperlink_16 = uihyperlink(app.IncreasedormaintainedinundationTab);
            app.Hyperlink_16.URL = 'https://tnc.box.com/s/80wzj8zqdtxqqvscbq62brxgpc7ihtw2';
            app.Hyperlink_16.Text = 'Appendix A-11';
            app.GGI5_Param_4 = uieditfield(app.IncreasedormaintainedinundationTab, 'numeric');
            app.GGI5_Param_4.Limits = [1 Inf];
            app.GGI5_Param_4.ValueDisplayFormat = '%.d';
            app.GGI5_Param_4.Value = 1;
            app.GGI5_Param_3 = uieditfield(app.IncreasedormaintainedinundationTab, 'numeric');
            app.GGI5_Param_3.Limits = [0 Inf];
            app.GGI5_Param_2 = uieditfield(app.IncreasedormaintainedinundationTab, 'numeric');
            app.GGI5_Param_2.Limits = [0 Inf];
            app.CO2eWithActivitiesLabel_49 = uilabel(app.IncreasedormaintainedinundationTab);
            app.CO2eWithActivitiesLabel_49.Text = {'Average water depth -'; 'Without project (m) '};
            app.CO2eWithActivitiesLabel_50 = uilabel(app.IncreasedormaintainedinundationTab);
            app.CO2eWithActivitiesLabel_50.Text = {'Surface area '; 'inundated (ha) '};
            app.GGI5_Param_1 = uieditfield(app.IncreasedormaintainedinundationTab, 'numeric');
            app.GGI5_Param_1.Limits = [0 Inf];
            app.CO2eWithActivitiesLabel_51 = uilabel(app.IncreasedormaintainedinundationTab);
            app.CO2eWithActivitiesLabel_51.Text = {'Average number of '; 'inundations'};
            app.CO2eWithActivitiesLabel_56 = uilabel(app.IncreasedormaintainedinundationTab);
            app.CO2eWithActivitiesLabel_56.Text = {'Average water depth -'; 'With project (m) '};
            app.IncreasedrechargeTab = uitab(app.TabGroup2_4);
            app.IncreasedrechargeTab.Title = 'Increased recharge';
            app.Label_154 = uilabel(app.IncreasedrechargeTab);
            app.Label_154.Text = 'In-stream barrier removal, dam reoperation, floodplain reconnection , levee or berm removal, side channel reconnection, riparian habitat improvements, process-based restoration, wet meadow restoration, beaver dam analogs, water level management for habitat, wetland or peat bog protection or restoration, wetland creation and others';
            app.Label_155 = uilabel(app.IncreasedrechargeTab);
            app.Label_155.Text = 'Sub-activities';
            app.Label_156 = uilabel(app.IncreasedrechargeTab);
            app.Label_156.Text = 'Increased or maintained recharge';
            app.Label_157 = uilabel(app.IncreasedrechargeTab);
            app.Label_157.Text = {'Volumetric '; 'objective '};
            app.Label_158 = uilabel(app.IncreasedrechargeTab);
            app.Label_158.Text = 'Recharge method';
            app.Label_159 = uilabel(app.IncreasedrechargeTab);
            app.Label_159.Text = 'The Recharge method enables volumetric benefit estimation of activities that directly increase recharge or create, restore or protect waterbodies where recharge is the primary hydrologic function impacted by the activity';
            app.OuputsPanel_24 = uipanel(app.IncreasedrechargeTab);
            app.OuputsPanel_24.Title = 'Ouputs';
            app.Button_GGI7 = uibutton(app.OuputsPanel_24, 'push');
            app.Button_GGI7.ButtonPushedFcn = createCallbackFcn(app, @Button_GGI7Pushed, true);
            app.Button_GGI7.Icon = 'Icons_Play.png';
            app.Button_GGI7.Text = 'Processing';
            app.VolumenrunoffLabel_13 = uilabel(app.OuputsPanel_24);
            app.VolumenrunoffLabel_13.Interpreter = 'latex';
            app.VolumenrunoffLabel_13.Text = '\bf VWB $\bf (m^{3}/yr)$';
            app.Index_GGI7 = uieditfield(app.OuputsPanel_24, 'numeric');
            app.Index_GGI7.Editable = 'off';
            app.Hyperlink_19 = uihyperlink(app.IncreasedrechargeTab);
            app.Hyperlink_19.URL = 'https://tnc.box.com/s/lf1xe99bwqi213bqvnnqtiidjiulhmoh';
            app.Hyperlink_19.Text = 'Appendix A-4';
            app.GGI7_Param_7 = uieditfield(app.IncreasedrechargeTab, 'numeric');
            app.GGI7_Param_7.Limits = [1 Inf];
            app.GGI7_Param_7.ValueDisplayFormat = '%.d';
            app.GGI7_Param_7.Value = 1;
            app.GGI7_Param_6 = uieditfield(app.IncreasedrechargeTab, 'numeric');
            app.GGI7_Param_6.Limits = [0 Inf];
            app.GGI7_Param_5 = uieditfield(app.IncreasedrechargeTab, 'numeric');
            app.GGI7_Param_5.Limits = [0 Inf];
            app.GGI7_Param_4 = uieditfield(app.IncreasedrechargeTab, 'numeric');
            app.GGI7_Param_4.Limits = [0 Inf];
            app.CO2eWithActivitiesLabel_61 = uilabel(app.IncreasedrechargeTab);
            app.CO2eWithActivitiesLabel_61.Text = 'Evaporation (mm)';
            app.CO2eWithActivitiesLabel_63 = uilabel(app.IncreasedrechargeTab);
            app.CO2eWithActivitiesLabel_63.Text = {'Design storage '; 'capacity (m3)'};
            app.CO2eWithActivitiesLabel_64 = uilabel(app.IncreasedrechargeTab);
            app.CO2eWithActivitiesLabel_64.Text = 'Withdrawal (m3)';
            app.CO2eWithActivitiesLabel_65 = uilabel(app.IncreasedrechargeTab);
            app.CO2eWithActivitiesLabel_65.Text = {'Number of times '; 'filled to capacity '};
            app.GGI7_Param_3 = uieditfield(app.IncreasedrechargeTab, 'numeric');
            app.GGI7_Param_3.Limits = [0 1];
            app.GGI7_Param_2 = uieditfield(app.IncreasedrechargeTab, 'numeric');
            app.GGI7_Param_2.Limits = [0 Inf];
            app.CO2eWithActivitiesLabel_66 = uilabel(app.IncreasedrechargeTab);
            app.CO2eWithActivitiesLabel_66.Text = {'Catchment'; 'area (ha)'};
            app.CO2eWithActivitiesLabel_67 = uilabel(app.IncreasedrechargeTab);
            app.CO2eWithActivitiesLabel_67.Text = {'Annual average '; 'rainfall (mm)'};
            app.GGI7_Param_1 = uieditfield(app.IncreasedrechargeTab, 'numeric');
            app.GGI7_Param_1.Limits = [0 Inf];
            app.CO2eWithActivitiesLabel_68 = uilabel(app.IncreasedrechargeTab);
            app.CO2eWithActivitiesLabel_68.Text = {'Runoff '; 'coefficient (Ad)'};
            app.Button_GDB_GG7_2 = uibutton(app.IncreasedrechargeTab, 'push');
            app.Button_GDB_GG7_2.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_GG7_2Pushed, true);
            app.Button_GDB_GG7_2.Icon = 'Icons_DataBase.png';
            app.Button_GDB_GG7_2.Text = {'Calculate from '; 'Global Database'};
            app.Button_GDB_GG7_1 = uibutton(app.IncreasedrechargeTab, 'push');
            app.Button_GDB_GG7_1.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_GG7_1Pushed, true);
            app.Button_GDB_GG7_1.Icon = 'Icons_DataBase.png';
            app.Button_GDB_GG7_1.Text = {'Calculate from '; 'Global Database'};
            app.VolumeimprovedTab = uitab(app.TabGroup2_4);
            app.VolumeimprovedTab.Title = 'Volume improved';
            app.Label_160 = uilabel(app.VolumeimprovedTab);
            app.Label_160.Text = 'In-stream barrier removal, dam reoperation, floodplain reconnection , levee or berm removal, side channel reconnection, riparian habitat improvements, process-based restoration, wet meadow restoration, beaver dam analogs, water level management for habitat, wetland or peat bog protection or restoration, wetland creation and others';
            app.Label_161 = uilabel(app.VolumeimprovedTab);
            app.Label_161.Text = 'Sub-activities';
            app.Label_162 = uilabel(app.VolumeimprovedTab);
            app.Label_162.Text = 'Improved water quality through nonpoint source pollution reduction';
            app.Label_163 = uilabel(app.VolumeimprovedTab);
            app.Label_163.Text = {'Volumetric '; 'objective '};
            app.Label_164 = uilabel(app.VolumeimprovedTab);
            app.Label_164.Text = 'Nonpoint Source Pollutant Reduction Method';
            app.Label_165 = uilabel(app.VolumeimprovedTab);
            app.Label_165.Text = 'The Nonpoint Source Pollutant Reduction Method enables estimation of the volumetric benefit of activities that improve water quality by avoiding or controlling nonpoint source pollutant loading originating from nonpoint sources.';
            app.Button_GDB_GG6_1 = uibutton(app.VolumeimprovedTab, 'push');
            app.Button_GDB_GG6_1.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_GG6_1Pushed, true);
            app.Button_GDB_GG6_1.Icon = 'Icons_DataBase.png';
            app.Button_GDB_GG6_1.Text = {'Calculate from '; 'Global Database'};
            app.OuputsPanel_25 = uipanel(app.VolumeimprovedTab);
            app.OuputsPanel_25.Title = 'Ouputs';
            app.Button_Processing_GGI6 = uibutton(app.OuputsPanel_25, 'push');
            app.Button_Processing_GGI6.ButtonPushedFcn = createCallbackFcn(app, @Button_Processing_GGI6Pushed, true);
            app.Button_Processing_GGI6.Icon = 'Icons_Play.png';
            app.Button_Processing_GGI6.Text = 'Processing';
            app.VolumenrunoffLabel_14 = uilabel(app.OuputsPanel_25);
            app.VolumenrunoffLabel_14.Interpreter = 'latex';
            app.VolumenrunoffLabel_14.Text = '\bf VWB $\bf (m^{3}/yr)$';
            app.Index_GGI6 = uieditfield(app.OuputsPanel_25, 'numeric');
            app.Index_GGI6.Editable = 'off';
            app.Hyperlink_21 = uihyperlink(app.VolumeimprovedTab);
            app.Hyperlink_21.URL = 'https://tnc.box.com/s/rot0b0c4wutwe12iq3l1biw2kb4h1jj8';
            app.Hyperlink_21.Text = 'Appendix A-13';
            app.GGI6_Param_5 = uieditfield(app.VolumeimprovedTab, 'numeric');
            app.GGI6_Param_5.Limits = [1 Inf];
            app.GGI6_Param_5.Value = 1;
            app.GGI6_Param_4 = uieditfield(app.VolumeimprovedTab, 'numeric');
            app.GGI6_Param_4.Limits = [0 Inf];
            app.GGI6_Param_3 = uieditfield(app.VolumeimprovedTab, 'numeric');
            app.GGI6_Param_3.Limits = [1 Inf];
            app.GGI6_Param_3.Value = 1;
            app.GGI6_Param_2 = uieditfield(app.VolumeimprovedTab, 'numeric');
            app.GGI6_Param_2.Limits = [0 Inf];
            app.CO2eWithActivitiesLabel_69 = uilabel(app.VolumeimprovedTab);
            app.CO2eWithActivitiesLabel_69.Interpreter = 'latex';
            app.CO2eWithActivitiesLabel_69.Text = '\bf Post-Project Runoff $\bf (m3)$';
            app.GGI6_Param_1 = uieditfield(app.VolumeimprovedTab, 'numeric');
            app.GGI6_Param_1.Limits = [0 Inf];
            app.CO2eWithActivitiesLabel_70 = uilabel(app.VolumeimprovedTab);
            app.CO2eWithActivitiesLabel_70.Interpreter = 'latex';
            app.CO2eWithActivitiesLabel_70.Text = {'\bf Incremental Improvement '; '\bf by Activity'};
            app.CO2eWithActivitiesLabel_71 = uilabel(app.VolumeimprovedTab);
            app.CO2eWithActivitiesLabel_71.Interpreter = 'latex';
            app.CO2eWithActivitiesLabel_71.Text = {'\bf Number of WQ Challenges '; '\bf Addressed by Activity '};
            app.CO2eWithActivitiesLabel_72 = uilabel(app.VolumeimprovedTab);
            app.CO2eWithActivitiesLabel_72.Interpreter = 'latex';
            app.CO2eWithActivitiesLabel_72.Text = {'\bf Total Improvement '; '\bf Needed '};
            app.CO2eWithActivitiesLabel_73 = uilabel(app.VolumeimprovedTab);
            app.CO2eWithActivitiesLabel_73.Interpreter = 'latex';
            app.CO2eWithActivitiesLabel_73.Text = {'\bf Number of WQ Challenges '; '\bf Caused by Land Use'};
            app.Label_74 = uilabel(app.GreenandgrayinfrastructureTab);
            app.Label_74.Text = 'Green infrastructure refers to natural systems including rain gardens, bioswales, stormwater detention or retention ponds, pond dredging/desilting, drainage water management, blind inlets, and others designed to capture runoff';
            app.LandconservationandrestorationTab = uitab(app.TabGroup);
            app.LandconservationandrestorationTab.Title = 'Land conservation and restoration';
            app.Panel_CR = uipanel(app.LandconservationandrestorationTab);
            app.Panel_CR.Enable = 'off';
            app.TabGroup2_2 = uitabgroup(app.Panel_CR);
            app.AvoidedrunoffTab = uitab(app.TabGroup2_2);
            app.AvoidedrunoffTab.Title = 'Avoided runoff';
            app.Label_24 = uilabel(app.AvoidedrunoffTab);
            app.Label_24.Text = 'Curve number method';
            app.Label_25 = uilabel(app.AvoidedrunoffTab);
            app.Label_25.Text = 'The Curve Number Runoff method is an empirical method for estimating runoff quantities based on land cover, use, soil, and slope, accounting for temporal changes in precipitation and soil water content';
            app.CurvenumberRasterLabel_2 = uilabel(app.AvoidedrunoffTab);
            app.CurvenumberRasterLabel_2.Text = {'Curve number'; 'Without Activities'};
            app.CN_H_2 = uieditfield(app.AvoidedrunoffTab, 'numeric');
            app.CN_H_2.Limits = [0 100];
            app.CurvenumberWithActivitiesLabel_2 = uilabel(app.AvoidedrunoffTab);
            app.CurvenumberWithActivitiesLabel_2.Text = {'Curve number'; 'With Activities'};
            app.CN_F_2 = uieditfield(app.AvoidedrunoffTab, 'numeric');
            app.CN_F_2.Limits = [0 100];
            app.Label_26 = uilabel(app.AvoidedrunoffTab);
            app.Label_26.Text = 'Forest conservation, meadow conservation, grassland conservation, and other activities that preserve land vegetation cover.';
            app.Label_27 = uilabel(app.AvoidedrunoffTab);
            app.Label_27.Text = 'Sub-activities';
            app.Label_28 = uilabel(app.AvoidedrunoffTab);
            app.Label_28.Text = 'Improved water quality through nonpoint source pollution reduction';
            app.Label_29 = uilabel(app.AvoidedrunoffTab);
            app.Label_29.Text = {'Volumetric '; 'objective '};
            app.TimeseriesdailyprecipitationcsvLabel_2 = uilabel(app.AvoidedrunoffTab);
            app.TimeseriesdailyprecipitationcsvLabel_2.Text = {'Time series daily '; 'precipitation (csv)'};
            app.Edit_TS_Path_2 = uieditfield(app.AvoidedrunoffTab, 'text');
            app.Edit_TS_Path_2.Editable = 'off';
            app.OuputsPanel_4 = uipanel(app.AvoidedrunoffTab);
            app.OuputsPanel_4.Title = 'Ouputs';
            app.Button_CR = uibutton(app.OuputsPanel_4, 'push');
            app.Button_CR.ButtonPushedFcn = createCallbackFcn(app, @Button_CRPushed, true);
            app.Button_CR.Icon = 'Icons_Play.png';
            app.Button_CR.Text = 'Processing';
            app.Reducedrunoffm3Label_2 = uilabel(app.OuputsPanel_4);
            app.Reducedrunoffm3Label_2.Interpreter = 'latex';
            app.Reducedrunoffm3Label_2.Text = '\bf VWB $\bf (m^{3}/yr)$';
            app.Index_CR = uieditfield(app.OuputsPanel_4, 'numeric');
            app.Index_CR.Editable = 'off';
            app.Button_Load_TS_2 = uibutton(app.AvoidedrunoffTab, 'push');
            app.Button_Load_TS_2.ButtonPushedFcn = createCallbackFcn(app, @Button_Load_TS_2Pushed, true);
            app.Button_Load_TS_2.Icon = 'Icons_NewProject.png';
            app.Button_Load_TS_2.Text = 'Load';
            app.Button_GDB_TS_14 = uibutton(app.AvoidedrunoffTab, 'push');
            app.Button_GDB_TS_14.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_TS_14Pushed, true);
            app.Button_GDB_TS_14.Icon = 'Icons_DataBase.png';
            app.Button_GDB_TS_14.Text = {'Calculate from '; 'Global Database'};
            app.Button_GDB_CN_F_2 = uibutton(app.AvoidedrunoffTab, 'push');
            app.Button_GDB_CN_F_2.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_CN_F_2Pushed, true);
            app.Button_GDB_CN_F_2.Icon = 'Icons_DataBase.png';
            app.Button_GDB_CN_F_2.Text = {'Calculate from '; 'Global Database'};
            app.Button_GDB_CN_H_2 = uibutton(app.AvoidedrunoffTab, 'push');
            app.Button_GDB_CN_H_2.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_CN_H_2Pushed, true);
            app.Button_GDB_CN_H_2.Icon = 'Icons_DataBase.png';
            app.Button_GDB_CN_H_2.Text = {'Calculate from '; 'Global Database'};
            app.Hyperlink_2 = uihyperlink(app.AvoidedrunoffTab);
            app.Hyperlink_2.URL = 'https://tnc.box.com/s/j64zeqqka9a8b75v6vn88ocbga9ftsac';
            app.Hyperlink_2.Text = 'Appendix A-1';
            app.MaintainedrechargeTab = uitab(app.TabGroup2_2);
            app.MaintainedrechargeTab.Title = 'Maintained recharge';
            app.Label_35 = uilabel(app.MaintainedrechargeTab);
            app.Label_35.Text = 'This method is used to estimate the volume of water associated with activities that improve or restore soil health. Similar to how lakes, rivers and aquifers provide critical storage of freshwater resources, healthy soils also capture and store freshwater that is made available to plants and microfauna.';
            app.Label_30 = uilabel(app.MaintainedrechargeTab);
            app.Label_30.Text = 'Reforestation, grassland restoration, and other activities that restore vegetation cover';
            app.Label_31 = uilabel(app.MaintainedrechargeTab);
            app.Label_31.Text = 'Sub-activities';
            app.Label_32 = uilabel(app.MaintainedrechargeTab);
            app.Label_32.Text = 'Improved water balance through increased supply';
            app.Label_33 = uilabel(app.MaintainedrechargeTab);
            app.Label_33.Text = {'Volumetric '; 'objective '};
            app.Label_34 = uilabel(app.MaintainedrechargeTab);
            app.Label_34.Text = 'Increased recharge and seasonal water availability method';
            app.Button_Load_TS_P_CR = uibutton(app.MaintainedrechargeTab, 'push');
            app.Button_Load_TS_P_CR.ButtonPushedFcn = createCallbackFcn(app, @Button_Load_TS_P_CRPushed, true);
            app.Button_Load_TS_P_CR.Icon = 'Icons_NewProject.png';
            app.Button_Load_TS_P_CR.Text = 'Load';
            app.Button_Load_TS_ET_CR = uibutton(app.MaintainedrechargeTab, 'push');
            app.Button_Load_TS_ET_CR.ButtonPushedFcn = createCallbackFcn(app, @Button_Load_TS_ET_CRPushed, true);
            app.Button_Load_TS_ET_CR.Icon = 'Icons_NewProject.png';
            app.Button_Load_TS_ET_CR.Text = 'Load';
            app.Button_GDB_TS_P_CR = uibutton(app.MaintainedrechargeTab, 'push');
            app.Button_GDB_TS_P_CR.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_TS_P_CRPushed, true);
            app.Button_GDB_TS_P_CR.Icon = 'Icons_DataBase.png';
            app.Button_GDB_TS_P_CR.Text = {'Calculate from '; 'Global Database'};
            app.Button_GDB_TS__ET_CR = uibutton(app.MaintainedrechargeTab, 'push');
            app.Button_GDB_TS__ET_CR.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_TS__ET_CRPushed, true);
            app.Button_GDB_TS__ET_CR.Icon = 'Icons_DataBase.png';
            app.Button_GDB_TS__ET_CR.Text = {'Calculate from '; 'Global Database'};
            app.Button_Load_TS_CS_CR = uibutton(app.MaintainedrechargeTab, 'push');
            app.Button_Load_TS_CS_CR.ButtonPushedFcn = createCallbackFcn(app, @Button_Load_TS_CS_CRPushed, true);
            app.Button_Load_TS_CS_CR.Icon = 'Icons_NewProject.png';
            app.Button_Load_TS_CS_CR.Text = 'Load';
            app.Button_GDB_CN_P_CR = uibutton(app.MaintainedrechargeTab, 'push');
            app.Button_GDB_CN_P_CR.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_CN_P_CRPushed, true);
            app.Button_GDB_CN_P_CR.Icon = 'Icons_DataBase.png';
            app.Button_GDB_CN_P_CR.Text = {'Calculate from '; 'Global Database'};
            app.Button_GDB_CN_H_CR = uibutton(app.MaintainedrechargeTab, 'push');
            app.Button_GDB_CN_H_CR.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_CN_H_CRPushed, true);
            app.Button_GDB_CN_H_CR.Icon = 'Icons_DataBase.png';
            app.Button_GDB_CN_H_CR.Text = {'Calculate from '; 'Global Database'};
            app.Button_GDB_FC_CR = uibutton(app.MaintainedrechargeTab, 'push');
            app.Button_GDB_FC_CR.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_FC_CRPushed, true);
            app.Button_GDB_FC_CR.Icon = 'Icons_DataBase.png';
            app.Button_GDB_FC_CR.Text = {'Calculate from '; 'Global Database'};
            app.Button_GDB_Smax_CR = uibutton(app.MaintainedrechargeTab, 'push');
            app.Button_GDB_Smax_CR.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_Smax_CRPushed, true);
            app.Button_GDB_Smax_CR.Icon = 'Icons_DataBase.png';
            app.Button_GDB_Smax_CR.Text = {'Calculate from '; 'Global Database'};
            app.TimeseriesdailyprecipitationcsvLabel_7 = uilabel(app.MaintainedrechargeTab);
            app.TimeseriesdailyprecipitationcsvLabel_7.Text = {'Time series daily '; 'evapotranspiration (csv)'};
            app.TimeseriesdailyprecipitationcsvLabel_6 = uilabel(app.MaintainedrechargeTab);
            app.TimeseriesdailyprecipitationcsvLabel_6.Text = {'Time series daily '; 'precipitation (csv)'};
            app.TS_Path_P_CR = uieditfield(app.MaintainedrechargeTab, 'text');
            app.TS_Path_P_CR.Editable = 'off';
            app.TS_Path_ET_CR = uieditfield(app.MaintainedrechargeTab, 'text');
            app.TS_Path_ET_CR.Editable = 'off';
            app.TS_Path_CS_CR = uieditfield(app.MaintainedrechargeTab, 'text');
            app.TS_Path_CS_CR.Editable = 'off';
            app.CurvenumberWithActivitiesLabel_14 = uilabel(app.MaintainedrechargeTab);
            app.CurvenumberWithActivitiesLabel_14.Text = {'Curve number'; 'With Activities'};
            app.CN_F_CR = uieditfield(app.MaintainedrechargeTab, 'numeric');
            app.CN_F_CR.Limits = [0 100];
            app.CurvenumberRasterLabel_16 = uilabel(app.MaintainedrechargeTab);
            app.CurvenumberRasterLabel_16.Text = {'Curve number'; 'Without Activities'};
            app.CN_H_CR = uieditfield(app.MaintainedrechargeTab, 'numeric');
            app.CN_H_CR.Limits = [0 100];
            app.CurvenumberRasterLabel_18 = uilabel(app.MaintainedrechargeTab);
            app.CurvenumberRasterLabel_18.Text = {'Soil water content at '; 'field capacity (mm)'};
            app.CC_CR = uieditfield(app.MaintainedrechargeTab, 'numeric');
            app.CC_CR.Limits = [0 Inf];
            app.CurvenumberRasterLabel_19 = uilabel(app.MaintainedrechargeTab);
            app.CurvenumberRasterLabel_19.Text = {'Soil water content at'; 'saturation (mm)'};
            app.Smax_CR = uieditfield(app.MaintainedrechargeTab, 'numeric');
            app.Smax_CR.Limits = [0 Inf];
            app.OuputsPanel_5 = uipanel(app.MaintainedrechargeTab);
            app.OuputsPanel_5.Title = 'Ouputs';
            app.VolumenrunoffLabel_3 = uilabel(app.OuputsPanel_5);
            app.VolumenrunoffLabel_3.Interpreter = 'latex';
            app.VolumenrunoffLabel_3.Text = {'\bf Increased'; '\bf recharge ${\bf (m^3/yr)}$'};
            app.Button_Processing_4 = uibutton(app.OuputsPanel_5, 'push');
            app.Button_Processing_4.ButtonPushedFcn = createCallbackFcn(app, @Button_Processing_4Pushed, true);
            app.Button_Processing_4.Icon = 'Icons_Play.png';
            app.Button_Processing_4.Text = 'Processing';
            app.VolumenrunoffLabel_8 = uilabel(app.OuputsPanel_5);
            app.VolumenrunoffLabel_8.Interpreter = 'latex';
            app.VolumenrunoffLabel_8.Text = {'\bf Increased'; '\bf SWS ${\bf (m^3/yr)}$'};
            app.Index_CR_Sw = uieditfield(app.OuputsPanel_5, 'numeric');
            app.Index_CR_Sw.Editable = 'off';
            app.Index_CR_R = uieditfield(app.OuputsPanel_5, 'numeric');
            app.Index_CR_R.Editable = 'off';
            app.Button_GDB_CS_CR = uibutton(app.MaintainedrechargeTab, 'push');
            app.Button_GDB_CS_CR.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_CS_CRPushed, true);
            app.Button_GDB_CS_CR.Icon = 'Icons_DataBase.png';
            app.Button_GDB_CS_CR.Text = {'Calculate from '; 'Global Database'};
            app.TimeseriesdailyprecipitationcsvLabel_8 = uilabel(app.MaintainedrechargeTab);
            app.TimeseriesdailyprecipitationcsvLabel_8.Text = {'Time series daily '; 'canopy Storage (csv)'};
            app.Button_GDB_Rr_CR = uibutton(app.MaintainedrechargeTab, 'push');
            app.Button_GDB_Rr_CR.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_Rr_CRPushed, true);
            app.Button_GDB_Rr_CR.Icon = 'Icons_DataBase.png';
            app.Button_GDB_Rr_CR.Text = {'Calculate from '; 'Global Database'};
            app.CurvenumberRasterLabel_20 = uilabel(app.MaintainedrechargeTab);
            app.CurvenumberRasterLabel_20.Text = {'Saturation hydraulic '; 'conductivity (mm/d)'};
            app.Rr_CR = uieditfield(app.MaintainedrechargeTab, 'numeric');
            app.Rr_CR.Limits = [0 Inf];
            app.Button_GDB_PWA_CR = uibutton(app.MaintainedrechargeTab, 'push');
            app.Button_GDB_PWA_CR.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_PWA_CRPushed, true);
            app.Button_GDB_PWA_CR.Icon = 'Icons_DataBase.png';
            app.Button_GDB_PWA_CR.Text = {'Calculate from '; 'Global Database'};
            app.CurvenumberRasterLabel_21 = uilabel(app.MaintainedrechargeTab);
            app.CurvenumberRasterLabel_21.Text = {'Soil water content at'; 'permanent wilting '; 'point (mm)'};
            app.PWP_CR = uieditfield(app.MaintainedrechargeTab, 'numeric');
            app.PWP_CR.Limits = [0 Inf];
            app.Hyperlink_20 = uihyperlink(app.MaintainedrechargeTab);
            app.Hyperlink_20.URL = 'https://tnc.box.com/s/1ktwp6vo1aue8c78imxgy576ktzmmf22';
            app.Hyperlink_20.Text = 'Appendix A-15';
            app.Label_36 = uilabel(app.LandconservationandrestorationTab);
            app.Label_36.Text = 'Land restoration refers to the process of halting degradation or rehabilitating degraded land through activities such as reforestation and other actions that restore vegetative cover. Conservation focuses on the protection of natural areas through activities such as forest conservation and other actions that preserve the land''s vegetative cover.';
            app.Button_Porfolio_CR = uibutton(app.LandconservationandrestorationTab, 'push');
            app.Button_Porfolio_CR.ButtonPushedFcn = createCallbackFcn(app, @Button_Porfolio_CRPushed, true);
            app.Button_Porfolio_CR.Icon = 'Icons_Raster.png';
            app.Button_Porfolio_CR.Text = {'Spatially distribute '; 'area and obtain raster'};
            app.Lamp_CR = uilamp(app.LandconservationandrestorationTab);
            app.AreahaEditFieldLabel_2 = uilabel(app.LandconservationandrestorationTab);
            app.AreahaEditFieldLabel_2.Text = 'Area (ha)';
            app.Area_CR = uieditfield(app.LandconservationandrestorationTab, 'numeric');
            app.ConservationLabel = uilabel(app.LandconservationandrestorationTab);
            app.ConservationLabel.Text = 'Conservation (%)';
            app.PercentageArea_Con = uieditfield(app.LandconservationandrestorationTab, 'numeric');
            app.PercentageArea_Con.Limits = [0 100];
            app.PercentageArea_Con.Value = 50;
            app.WASHTab = uitab(app.TabGroup);
            app.WASHTab.Title = 'WASH';
            app.Panel_WASH = uipanel(app.WASHTab);
            app.TabGroup2_5 = uitabgroup(app.Panel_WASH);
            app.VolumeprovidedTab_3 = uitab(app.TabGroup2_5);
            app.VolumeprovidedTab_3.Title = 'Volume provided';
            app.Label_124 = uilabel(app.VolumeprovidedTab_3);
            app.Label_124.Text = 'Activities that increase access to drinking water supply';
            app.Label_125 = uilabel(app.VolumeprovidedTab_3);
            app.Label_125.Text = 'Sub-activities';
            app.Label_126 = uilabel(app.VolumeprovidedTab_3);
            app.Label_126.Text = 'Improved WASH';
            app.Label_127 = uilabel(app.VolumeprovidedTab_3);
            app.Label_127.Text = {'Volumetric '; 'objective '};
            app.Label_128 = uilabel(app.VolumeprovidedTab_3);
            app.Label_128.Text = 'Volume Provided Method';
            app.Label_129 = uilabel(app.VolumeprovidedTab_3);
            app.Label_129.Text = 'The Volume Provided method enables estimation of the volumetric benefit of activities that provide a volume of water that contributes to improving human health and/or livelihood, as well as social or economic security';
            app.Button_GDB_RPerCapita_2 = uibutton(app.VolumeprovidedTab_3, 'push');
            app.Button_GDB_RPerCapita_2.ButtonPushedFcn = createCallbackFcn(app, @Button_GDB_RPerCapita_2Pushed, true);
            app.Button_GDB_RPerCapita_2.Icon = 'Icons_DataBase.png';
            app.Button_GDB_RPerCapita_2.Text = {'Calculate from '; 'Global Database'};
            app.OuputsPanel_19 = uipanel(app.VolumeprovidedTab_3);
            app.OuputsPanel_19.Title = 'Ouputs';
            app.Button_WASH2 = uibutton(app.OuputsPanel_19, 'push');
            app.Button_WASH2.ButtonPushedFcn = createCallbackFcn(app, @Button_WASH2Pushed, true);
            app.Button_WASH2.Icon = 'Icons_Play.png';
            app.Button_WASH2.Text = 'Processing';
            app.VolumenrunoffLabel_9 = uilabel(app.OuputsPanel_19);
            app.VolumenrunoffLabel_9.Interpreter = 'latex';
            app.VolumenrunoffLabel_9.Text = '\bf VWB $\bf (m^{3})$';
            app.Index_WASH2 = uieditfield(app.OuputsPanel_19, 'numeric');
            app.Index_WASH2.Editable = 'off';
            app.Hyperlink_14 = uihyperlink(app.VolumeprovidedTab_3);
            app.Hyperlink_14.URL = 'https://tnc.box.com/s/cyj5vjbmq1o4ayhl053lyqy0uemjnjd5';
            app.Hyperlink_14.Text = 'Appendix A-3';
            app.CO2eWithActivitiesLabel_48 = uilabel(app.VolumeprovidedTab_3);
            app.CO2eWithActivitiesLabel_48.Text = {'Project '; 'duration (day)'};
            app.WASH2_Param_3 = uieditfield(app.VolumeprovidedTab_3, 'numeric');
            app.WASH2_Param_3.Limits = [0 Inf];
            app.WASH2_Param_3.ValueDisplayFormat = '%.d';
            app.WASH2_Param_2 = uieditfield(app.VolumeprovidedTab_3, 'numeric');
            app.WASH2_Param_2.Limits = [0 Inf];
            app.CO2eWithActivitiesLabel_46 = uilabel(app.VolumeprovidedTab_3);
            app.CO2eWithActivitiesLabel_46.Text = {'Required per-capita '; 'availability (L/capita/day)'};
            app.CO2eWithActivitiesLabel_47 = uilabel(app.VolumeprovidedTab_3);
            app.CO2eWithActivitiesLabel_47.Text = {'Number of '; 'beneficiaries '};
            app.WASH2_Param_1 = uieditfield(app.VolumeprovidedTab_3, 'numeric');
            app.WASH2_Param_1.Limits = [0 Inf];
            app.WASH2_Param_1.ValueDisplayFormat = '%.d';
            app.VolumetreatedTab_3 = uitab(app.TabGroup2_5);
            app.VolumetreatedTab_3.Title = 'Volume treated';
            app.Label_130 = uilabel(app.VolumetreatedTab_3);
            app.Label_130.Text = 'Volume Treated Method';
            app.Label_131 = uilabel(app.VolumetreatedTab_3);
            app.Label_131.Text = 'The Volume Treated method enables estimation of the volumetric benefit of activities that have the primary objective of improving water quality (WQ). ';
            app.Label_132 = uilabel(app.VolumetreatedTab_3);
            app.Label_132.Text = 'Activities that increase access to sanitation facilities where excreta are safely disposed of in situ or removed and treated offsite';
            app.Label_133 = uilabel(app.VolumetreatedTab_3);
            app.Label_133.Text = 'Sub-activities';
            app.Label_134 = uilabel(app.VolumetreatedTab_3);
            app.Label_134.Text = 'Improved WASH';
            app.Label_135 = uilabel(app.VolumetreatedTab_3);
            app.Label_135.Text = {'Volumetric '; 'objective '};
            app.OuputsPanel_20 = uipanel(app.VolumetreatedTab_3);
            app.OuputsPanel_20.Title = 'Ouputs';
            app.Button_WASH = uibutton(app.OuputsPanel_20, 'push');
            app.Button_WASH.ButtonPushedFcn = createCallbackFcn(app, @Button_WASHPushed, true);
            app.Button_WASH.Icon = 'Icons_Play.png';
            app.Button_WASH.Text = 'Processing';
            app.Reducedrunoffm3Label_12 = uilabel(app.OuputsPanel_20);
            app.Reducedrunoffm3Label_12.Interpreter = 'latex';
            app.Reducedrunoffm3Label_12.Text = '\bf VWB $\bf (m^{3})$';
            app.Index_WASH = uieditfield(app.OuputsPanel_20, 'numeric');
            app.Index_WASH.Editable = 'off';
            app.Hyperlink_15 = uihyperlink(app.VolumetreatedTab_3);
            app.Hyperlink_15.URL = 'https://tnc.box.com/s/b6bmorp3jnaapx6rn3toxhmd4rgme4w1';
            app.Hyperlink_15.Text = 'Appendix A-6';
            app.WASH_Table = uitable(app.VolumetreatedTab_3);
            app.WASH_Table.ColumnName = {'Challenges'; 'Fraction Improved (%)'};
            app.WASH_Table.RowName = {};
            app.WASH_Table.ColumnEditable = true;
            app.CurvenumberRasterLabel_22 = uilabel(app.VolumetreatedTab_3);
            app.CurvenumberRasterLabel_22.Interpreter = 'latex';
            app.CurvenumberRasterLabel_22.Text = {'\bf Annual volume of '; '\bf water treated $\bf (m^3)$'};
            app.WASH_Param_1 = uieditfield(app.VolumetreatedTab_3, 'numeric');
            app.WASH_Param_1.Limits = [0 Inf];
            app.ChallengesSpinner_2Label = uilabel(app.VolumetreatedTab_3);
            app.ChallengesSpinner_2Label.Text = 'Challenges';
            app.ChallengesSpinner_2 = uispinner(app.VolumetreatedTab_3);
            app.ChallengesSpinner_2.Limits = [1 10];
            app.ChallengesSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ChallengesSpinner_2ValueChanged, true);
            app.ChallengesSpinner_2.Value = 1;
            app.Label_93 = uilabel(app.WASHTab);
            app.Label_93.Text = 'Water, sanitation and hygiene (WAHS): The purposes of providing access to WASH services include achieving public health gains, improving human dignity in the case of sanitation, implementing the human right to water and sanitation, reducing the burden of collecting drinking water for women, reducing risks of violence against women, improving education and health outcomes at schools and health facilities, and reducing water pollution.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_1_VWBA_Tools

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end