classdef App_2_InfoProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        App_InfoProject               matlab.ui.Figure
        EnterprojectinformationPanel  matlab.ui.container.Panel
        CancelButton                  matlab.ui.control.Button
        Company                       matlab.ui.control.EditField
        CompanyEditFieldLabel         matlab.ui.control.Label
        Description                   matlab.ui.control.EditField
        DescriptionEditFieldLabel     matlab.ui.control.Label
        Name                          matlab.ui.control.EditField
        ProjectnameLabel              matlab.ui.control.Label
        Author                        matlab.ui.control.EditField
        AuthorEditFieldLabel          matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        ImageTNC                      matlab.ui.control.Image
        ImageN4W                      matlab.ui.control.Image
        Info                          matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Ruta del proyecto
        ProjectPath % Description
        % App principal
        AppVWBA
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            % Aplicación principal
            app.AppVWBA             = varargin{1};           
            % Ruta del proyecto
            app.ProjectPath         = app.AppVWBA.ProjectPath;
            % Datos del proyecto                
            UserData                = varargin{2};

            if ~isempty(UserData)
                % Ruta del proyecto
                app.ProjectPath         = UserData.ProjectPath;
                % Nombre del proyecto
                app.Name.Value          = UserData.ProjectName;
                % Autor del proyecto
                app.Author.Value        = UserData.Author;
                % Entidad
                app.Company.Value       = UserData.Company;
                % Descripción
                app.Description.Value   = UserData.Description;                
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % -------------------------------------------------------------
            % Leer datos ingresados por el usuario
            % -------------------------------------------------------------
            % Ruta del proyecto
            UserData.ProjectPath    = app.ProjectPath;
            % Nombre del proyecto
            UserData.ProjectName    = app.Name.Value;
            % Autor del proyecto
            UserData.Author         = app.Author.Value;
            % Entidad
            UserData.Company        = app.Company.Value;
            % Descripción
            UserData.Description    = app.Description.Value;
            % Nombre la región 
            UserData.NameRegion     = '';
            % Área de la cuenca
            UserData.BasinArea      = 0;
            % Área de bosque
            UserData.ForestArea     = 0;
            % Longitud de ríos
            UserData.LengthRivers   = 0;
            % Área de humedales
            UserData.LakesArea      = 0;
            % Áreas de importancia para la biodiversidad
            UserData.AreaBio        = [];
            % Numero de personas
            UserData.People         = 0;

            % Guardar estructra de información ingresada por el usuario
            save( fullfile(app.ProjectPath,'Project.mat'),'UserData')
            
            % Remplazar UserData en aplicación principal
            app.AppVWBA.UserData    = UserData;
            
            % Asignar ruta del proyecto
            app.ProjectPath         = UserData.ProjectPath;

            % Asignar nombre de proyecto
            app.AppVWBA.ProjectName.Value   = UserData.ProjectName;

            % Habilitar crear cuenca
            app.AppVWBA.CreatebasinMenu.Enable = "on";

            % Habilitar Edit Project Menu
            app.AppVWBA.EditProjectMenu.Enable = "on";

            % Habilitar reporte
            app.AppVWBA.CreatereportMenu.Enable = "on";

            % Habilitar funcionalidades App Principal
            app.AppVWBA.Enable_Project;   
            
            % Cerrar ventana 
            close(app.App_InfoProject)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Habilitar File Menu de App principal
            app.AppVWBA.FileMenu.Enable   = "on";

            % Habilitar Basin Menu de App principal
            app.AppVWBA.BasinMenu.Enable  = "on";  

            % Check si existe cuenca
            Check1 = isfile(fullfile(app.ProjectPath,'01-Basins','Basin.tif'));
            Check2 = isfile(fullfile(app.ProjectPath,'01-Basins','Basin.shp'));
            Check3 = isfile(fullfile(app.ProjectPath,'01-Basins','Point.shp'));
            
            % habilitar las funcionalidades de la app principal
            if Check1&&Check2&&Check3
                app.AppVWBA.Enable_Project;
            end
            
            % Cerrar ventanba 
            close(app.App_InfoProject)
        end

        % Close request function: App_InfoProject
        function App_InfoProjectCloseRequest(app, event)
            app.CancelButtonPushed;
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.App_InfoProject = uifigure('Visible', 'off');
            app.App_InfoProject.Name = 'MATLAB App';
            app.App_InfoProject.CloseRequestFcn = createCallbackFcn(app, @App_InfoProjectCloseRequest, true);
            app.EnterprojectinformationPanel = uipanel(app.App_InfoProject);
            app.EnterprojectinformationPanel.Title = 'Enter project information';
            app.Info = uilabel(app.EnterprojectinformationPanel);
            app.Info.Text = 'Fields marked with (*) are mandatory';
            app.ImageN4W = uiimage(app.EnterprojectinformationPanel);
            app.ImageN4W.ImageSource = 'Logo_N4W-Facility.png';
            app.ImageTNC = uiimage(app.EnterprojectinformationPanel);
            app.ImageTNC.ImageSource = 'Logo_TNC.png';
            app.SaveButton = uibutton(app.EnterprojectinformationPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'Icons_Save.png';
            app.SaveButton.Text = 'Save';
            app.AuthorEditFieldLabel = uilabel(app.EnterprojectinformationPanel);
            app.AuthorEditFieldLabel.Text = 'Author';
            app.Author = uieditfield(app.EnterprojectinformationPanel, 'text');
            app.ProjectnameLabel = uilabel(app.EnterprojectinformationPanel);
            app.ProjectnameLabel.Text = 'Project name (*)';
            app.Name = uieditfield(app.EnterprojectinformationPanel, 'text');
            app.DescriptionEditFieldLabel = uilabel(app.EnterprojectinformationPanel);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.Description = uieditfield(app.EnterprojectinformationPanel, 'text');
            app.CompanyEditFieldLabel = uilabel(app.EnterprojectinformationPanel);
            app.CompanyEditFieldLabel.Text = 'Company';
            app.Company = uieditfield(app.EnterprojectinformationPanel, 'text');
            app.Company.Value = 'The Nature Conservancy';
            app.CancelButton = uibutton(app.EnterprojectinformationPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = 'Icons_Cancel.png';
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.App_InfoProject.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_2_InfoProject(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.App_InfoProject)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.App_InfoProject)
        end
    end
end