classdef App_3_CreateBasins < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        App_CreateBasin                matlab.ui.Figure
        CreateBasinButton              matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        BasindelinationsPanel          matlab.ui.container.Panel
        Image2                         matlab.ui.control.Image
        ProjName                       matlab.ui.control.EditField
        ProjectnameEditFieldLabel      matlab.ui.control.Label
        Lat                            matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel         matlab.ui.control.Label
        Lon                            matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel        matlab.ui.control.Label
        CoordinatesWGS84EPSG4326Label  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        SaveButton                     matlab.ui.control.Button
        Map                            matlab.ui.container.Panel
    end

    
    properties (Access = public)
        % GeoAxes
        gx
        % Ruta del proyecto
        ProjectPath
        % Project Name
        ProjectName
        % Cuenca raster
        BasinRR
        % Cuenca shp
        BasinShp
        % Nombre la región
        NameR
        % Aplicación principal
        AppVWBA
        % Status Create
        StatusCreate = false;
    end
    
    methods (Access = public)
        % -------------------------------------------------------------
        % Create Prj
        % -------------------------------------------------------------
        function CreatePrj(app, PathShp)
            % Prj WGS84
            TextPrj = ['GEOGCS["GCS_WGS_1984",DATUM["D_WGS_1984",SPHEROID',...
                       '["WGS_1984",6378137.0,298.257223563]],PRIMEM',...
                       '["Greenwich",0.0],UNIT["Degree",0.0174532925199433]]'];

            % abrir archivo
            ID_File = fopen(PathShp,'w');

            % Escirbir datos
            fprintf(ID_File,'%s',TextPrj);
            
            % Cerrar archivo
            fclose(ID_File);
        end

        % -------------------------------------------------------------
        % Gráficar cuenca en la herramienta
        % -------------------------------------------------------------
        function PlotBasin(app, Latitude, Longitude, BasinShp)
            % Reiniciar gráfica
            delete(app.gx)
            app.gx = geoaxes(app.Map,"TickLabelFormat","dd","Basemap",'topographic');

            % Mantener varias gráficas
            hold(app.gx,"on")

            % Graficar punto de cierre de la cuenca
            geoscatter(app.gx,Latitude,Longitude,15,'filled','MarkerFaceColor','r','MarkerEdgeColor','k')

            % Graficar cuenca
            geoplot(app.gx,BasinShp.Y,BasinShp.X,'Color',[0 0.75 0.75])            
        end
        
        % -------------------------------------------------------------
        % Identificación de macro-región
        % -------------------------------------------------------------
        function NameRegion = AllocateRegion(app, Longitude, Latitude)
            % Cargar shapefile de macro-regiones
            Tmp     = matfile( fullfile(app.AppVWBA.DataBasePath,'Basin.mat') );
            Basin   = Tmp.Basin;
           
            % Lista de nombres de marcro-regiones 
            NameB   = {Basin.Label}';
            id      = false(length([Basin.Code]),1);
            for i = 1:length([Basin.Code])
                id(i) = inpolygon(Longitude,Latitude,[Basin(i).X],[Basin(i).Y]);
            end

            % Nombre de la macro-región donde se ubica el punto de salida
            NameRegion = NameB(id);
            NameRegion = NameRegion{1};
        end

        % -------------------------------------------------------------
        % Habilitar botones de app
        % -------------------------------------------------------------
        function EnableButtons(app)
            %  Habilitar botón de creación
            app.CreateBasinButton.Enable    = "on";
            %  Habilitar botón de cancelar
            app.CancelButton.Enable         = "on";
            %  Habilitar botón de guardar
            app.SaveButton.Enable           = "on";
        end

        % -------------------------------------------------------------
        % Deshabilitar botones de app
        % -------------------------------------------------------------
        function DisableButtons(app)
            %  Deshabilitar botón de creación
            app.CreateBasinButton.Enable    = "off";
            %  Deshabilitar botón de cancelar
            app.CancelButton.Enable         = "off";
            %  Deshabilitar botón de guardar
            app.SaveButton.Enable           = "off";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, AppVWBA, ProjectPath, ProjectName, StatusBasin)
            % Crear Axes Geográficos
            app.gx              = geoaxes(app.Map,"Basemap",'topographic');
            
            % Aplicación principal
            app.AppVWBA         = AppVWBA;

            % Asignar ruta del proyecto
            app.ProjectPath     = ProjectPath;

            % Asignar nombre del proyecto
            app.ProjectName     = ProjectName;
            app.ProjName.Value  = ProjectName;
            
            % Edición [StatusBasin = true] or Creación [StatusBasin = False]
            if StatusBasin
                % Leer cuenca
                app.BasinShp    = shaperead(fullfile(ProjectPath,'01-Basins','Basin.shp'));

                % Leer Punto de cierre
                Point           = shaperead(fullfile(ProjectPath,'01-Basins','Point.shp'));
                
                % Asignar coordenadas de punto de cierre
                app.Lon.Value   = Point.X;
                app.Lat.Value   = Point.Y;
                
                % Graficar
                PlotBasin(app, Point.Y, Point.X, app.BasinShp)

                % Status de creación de cuenca
                app.StatusCreate = true;
            end
        end

        % Button pushed function: CreateBasinButton
        function CreateBasinButtonPushed(app, event)
            try
                % Deshabiliatr botones
                app.DisableButtons;
    
                % Start waitbar
                wb = waitbar(0.1,'Starting processing','Color',[1 1 1]);
    
                % -------------------------------------------------------------
                % Lectura de datos ingresados por el usuario
                % -------------------------------------------------------------
                % Leer coordenadas - Latitude
                Longitude = app.Lon.Value;
                % Leer coordenadas - Latitude
                Latitude = app.Lat.Value;
                
                % -------------------------------------------------------------
                % Identificación de macro-región
                % -------------------------------------------------------------
                NameRegion = app.AllocateRegion(Longitude, Latitude);
                app.AppVWBA.NameRegion = NameRegion;
    
                % -------------------------------------------------------------
                % Delimitación de cuenca
                % -------------------------------------------------------------
                % Start waitbar
                waitbar(0.2,wb,['Loading Digital Elavation Model - Region: ', NameRegion]);
    
                % Lectura de DEM de macro-región 
                DEM     = GRIDobj( fullfile(app.AppVWBA.DataBasePath,'DEM',['DEM_',NameRegion,'.tif']) );
                
                % Progress
                waitbar(0.4,wb,['Processing Flow Direction - Region: ', NameRegion]);
    
                % Lectura de FlowDir de macro-región 
                Tmp     = matfile( fullfile(app.AppVWBA.DataBasePath,'FlowDir',['FlowDir_',NameRegion,'.mat']) );
                FlowDir = Tmp.FlowDir;
                
                % Progress
                waitbar(0.6,wb,['Processing Streams - Region: ', NameRegion]);
    
                % Lectura de Stream de macro-región 
                Tmp     = matfile( fullfile(app.AppVWBA.DataBasePath,'Stream',['Stream_',NameRegion,'.mat']) );
                Stream  = Tmp.Stream;
                
                % Progress
                waitbar(0.8,wb,'Delineation basin');
    
                % Snap Points
                [IX,~]      = coord2ind(DEM, Longitude, Latitude);         
            
                % Correction Points 
                [IXc,~]     = snap2stream(Stream,IX);
            
                % Delination Basin
                app.BasinRR = drainagebasins(FlowDir, IXc);
            
                % Export Shapfile 
                app.BasinShp = GRIDobj2polygon(app.BasinRR);
                            
                % Progress
                waitbar(0.95,wb,'Plot basin');
    
                % -------------------------------------------------------------
                % Gráficar cuenca en la herramienta
                % -------------------------------------------------------------
                PlotBasin(app, Latitude, Longitude, app.BasinShp)      
                     
                % Status de creación
                app.StatusCreate = true;
                
                % Progress
                waitbar(1,wb,'Processing Ok');
                close(wb);
    
                % Habilitar botones
                app.EnableButtons
            catch ME
                MessageLogger = sprintf('Error in function %s() at line %d. Error Message: %s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                errordlg(MessageLogger,'Error - Delineation basin')
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Deshabiliatr botones
            app.DisableButtons;
            
            % -------------------------------------------------------------
            % Lectura de datos ingresados por el usuario
            % -------------------------------------------------------------
            % Leer coordenadas - Latitude
            Longitude = app.Lon.Value;
            % Leer coordenadas - Latitude
            Latitude = app.Lat.Value;
            
            % -------------------------------------------------------------
            % Identificación de macro-región
            % -------------------------------------------------------------
            NameRegion = app.AllocateRegion(Longitude, Latitude);
            app.AppVWBA.NameRegion = NameRegion;
            
            % Guardar información de la cuenca en el proyecto 
            Tmp = matfile( fullfile(app.ProjectPath,'Project.mat'),'Writable',true);
            UserData            = Tmp.UserData;
            UserData.NameRegion = app.AppVWBA.NameRegion;            
            Tmp.UserData        = UserData;

            if ~app.StatusCreate
                % Mostrar mensaje de error
                errordlg("Please create a basin to save")
                % Habilitar botones
                app.EnableButtons
                return
            end
            
            % Check si existe cuenca
            Check1 = isfile(fullfile(app.ProjectPath,'01-Basins','Basin.shp'));
            Check2 = isfile(fullfile(app.ProjectPath,'01-Basins','Point.shp'));

            % Habilita Edit Basin Menu y Index
            if (Check1&&Check2)
                answer = questdlg('are you sure you want to overwrite the basin?', ...
	                'Save basin', ...
	                'Yes','No','No');
                % Handle response
                switch answer
                    case 'No'
                        % Habilitar botones
                        app.EnableButtons
                        return                        
                end
            end

            % Crear shapefile de puntos
            Point.Geometry  = 'Point';
            Point.X         = app.Lon.Value;
            Point.Y         = app.Lat.Value;
            Point.id        = 0;
            
            % Guardar cuenca como shapefile
            shapewrite(app.BasinShp, fullfile(app.ProjectPath,'01-Basins','Basin.shp'));
            app.CreatePrj(fullfile(app.ProjectPath,'01-Basins','Basin.prj'));

            % Guardar punto de cierre de la cuenca como shapefile
            shapewrite(Point, fullfile(app.ProjectPath,'01-Basins','Point.shp'));
            app.CreatePrj(fullfile(app.ProjectPath,'01-Basins','Point.prj'));

            % Guardar imagen de la cuenca para reporte html
            exportgraphics(app.Map,fullfile(app.ProjectPath,'04-Report','Basin.png'))

            % Recordar base de datos con la cuenca
            [app.AppVWBA.BasinArea,...
             app.AppVWBA.ForestArea,...
             app.AppVWBA.LengthRivers,...
             app.AppVWBA.LakesArea,...
             app.AppVWBA.AreaBio,...
             app.AppVWBA.People] = ClipInfo(app); 
            
            % Estructura de datos
            UserData                = Tmp.UserData;
            % Área de la cuenca en (m2)
            UserData.BasinArea      = app.AppVWBA.BasinArea;
            % Área de bósque en la cuenca (m2)
            UserData.ForestArea     = app.AppVWBA.ForestArea;
            % Longitud de ríos en la cuenca (km)
            UserData.LengthRivers   = app.AppVWBA.LengthRivers;
            % Área de humedales en la cuenca (m2)
            UserData.LakesArea      = app.AppVWBA.LakesArea;
            % Áreas de importancia para la biodiversidad (m2)
            UserData.AreaBio        = app.AppVWBA.AreaBio;
            % Número de personas
            UserData.People         = app.AppVWBA.People;
            % Guardar estructura
            Tmp.UserData            = UserData;         

            % habilitar boton de edición de cuenca
            app.AppVWBA.EditBasinMenu.Enable = true;
            
            % Habilitar funcionalidades de App principal
            app.AppVWBA.Enable_Project;
            
            % Cerrar ventana
            close(app.App_CreateBasin);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Habilitar funcionalidades de App principal
            app.AppVWBA.Enable_Project;
            
            % Check si existe cuenca
            Check1 = isfile(fullfile(app.ProjectPath,'01-Basins','Basin.shp'));
            Check2 = isfile(fullfile(app.ProjectPath,'01-Basins','Point.shp'));            
            
            % Habilita Edit Basin Menu y Index
            if (Check1&&Check2)
                app.AppVWBA.EditBasinMenu.Enable    = "on";
                app.AppVWBA.Panel_Index.Enable      = "on";
            else
                app.AppVWBA.EditBasinMenu.Enable    = "off";
                app.AppVWBA.Panel_Index.Enable      = "off";
            end

            % Cerrar ventana
            close(app.App_CreateBasin);
        end

        % Close request function: App_CreateBasin
        function App_CreateBasinCloseRequest(app, event)
            app.CancelButtonPushed;
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.App_CreateBasin = uifigure('Visible', 'off');
            app.App_CreateBasin.Name = 'MATLAB App';
            app.App_CreateBasin.CloseRequestFcn = createCallbackFcn(app, @App_CreateBasinCloseRequest, true);
            app.Map = uipanel(app.App_CreateBasin);
            app.SaveButton = uibutton(app.App_CreateBasin, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'Icons_Save.png';
            app.SaveButton.Text = 'Save';
            app.Image = uiimage(app.App_CreateBasin);
            app.Image.ImageSource = 'Logo_N4W-Facility.png';
            app.BasindelinationsPanel = uipanel(app.App_CreateBasin);
            app.BasindelinationsPanel.Title = 'Basin delinations';
            app.CoordinatesWGS84EPSG4326Label = uilabel(app.BasindelinationsPanel);
            app.CoordinatesWGS84EPSG4326Label.Text = 'Coordinates (WGS84 - EPSG: 4326)';
            app.LongitudeEditFieldLabel = uilabel(app.BasindelinationsPanel);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.Lon = uieditfield(app.BasindelinationsPanel, 'numeric');
            app.Lon.Limits = [-180 180];
            app.Lon.ValueDisplayFormat = '%0.3f';
            app.Lon.Value = -72.18;
            app.LatitudeEditFieldLabel = uilabel(app.BasindelinationsPanel);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.Lat = uieditfield(app.BasindelinationsPanel, 'numeric');
            app.Lat.Limits = [-90 90];
            app.Lat.ValueDisplayFormat = '%0.3f';
            app.Lat.Value = 4.23;
            app.ProjectnameEditFieldLabel = uilabel(app.BasindelinationsPanel);
            app.ProjectnameEditFieldLabel.Text = 'Project name';
            app.ProjName = uieditfield(app.BasindelinationsPanel, 'text');
            app.ProjName.Editable = 'off';
            app.Image2 = uiimage(app.BasindelinationsPanel);
            app.Image2.ImageSource = 'Logo_TNC.png';
            app.CancelButton = uibutton(app.App_CreateBasin, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = 'Icons_Cancel.png';
            app.CancelButton.Text = 'Cancel';
            app.CreateBasinButton = uibutton(app.App_CreateBasin, 'push');
            app.CreateBasinButton.ButtonPushedFcn = createCallbackFcn(app, @CreateBasinButtonPushed, true);
            app.CreateBasinButton.Icon = 'Icons_Basin.png';
            app.CreateBasinButton.Text = 'Create';
            % Show the figure after all components are created
            app.App_CreateBasin.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_3_CreateBasins(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.App_CreateBasin)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.App_CreateBasin)
        end
    end
end