classdef App_4_Report < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        App_Report       matlab.ui.Figure
        StatusCO2        matlab.ui.control.CheckBox
        StatusBio        matlab.ui.control.CheckBox
        PanelMetric      matlab.ui.container.Panel
        Check_UserTotal  matlab.ui.control.CheckBox
        VWB_UserTotal    matlab.ui.control.NumericEditField
        Dota             matlab.ui.control.NumericEditField
        TerrestrialinterventionareasEditFieldLabel  matlab.ui.control.Label
        TableMetrics     matlab.ui.control.Table
        StatusMetric     matlab.ui.control.CheckBox
        SelecttheindicatorsthatwillbecontainedinthereportLabel  matlab.ui.control.Label
        Button_WCN1      matlab.ui.control.Button
        VolumetricWaterBenefitAccountingLabel  matlab.ui.control.Label
        Image2           matlab.ui.control.Image
        Image            matlab.ui.control.Image
        Table            matlab.ui.control.Table
    end

    
    properties (Access = public)
        % Aplicación principal
        AppVWBA
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, VWBA)
            % Aplicación principal
            app.AppVWBA = VWBA;
            app.AppVWBA.Panel_Index.Enable = "off";

            % función de borrado de <br>
            Fun     = @(x) strrep(x,'<br>',' ');
            % Leer lista de indicadores
            Data    = readtable('Info_html.csv');
            Data    = varfun(Fun,Data);
            % Eliminar columnas
            Data(:,[1 4:6]) = [];

            % Incluir columna de valores lógicos estados de procesamiento
            Data = addvars(Data,app.AppVWBA.StatusProcess,'After','Fun_Index');

            % Incluir columna de valores lógicos
            Data = addvars(Data,app.AppVWBA.StatusProcess,'After','Var3');
            
            % Inicializar parametros de tabla
            app.Table.ColumnFormat = {'char','char','logical'};
            app.TableMetrics.ColumnFormat = {'char','logical'};
            % Mostrar datos en tabla
            app.Table.Data = Data;
            app.TableMetrics.Data = {'Estimated length of rivers benefited (km)',false;
                                     'Estimated area of wetlands benefited (ha)',false;
                                     'Estimated total volumetric water benefit (Cubic Meter per Year)',false;
                                     'Maximum potential number of people benefited downstream (people)',false};
        end

        % Button pushed function: Button_WCN1
        function Button_WCN1Pushed(app, event)
            % Habilitar funcionalidades App Principal
            app.AppVWBA.Enable_Project;  
                        
            % Crear reporte
            CreateReport(app)
            
            % Mesaje
            web(fullfile(app.AppVWBA.ProjectPath,'04-Report','Resport_VWBA.html'))
            % Cerrar ventana
            % close(app.App_Report);

        end

        % Value changed function: StatusMetric
        function StatusMetricValueChanged(app, event)
            value = app.StatusMetric.Value;
            if value
                app.PanelMetric.Enable = "on";
            else
                app.PanelMetric.Enable = "off";
            end
        end

        % Close request function: App_Report
        function App_ReportCloseRequest(app, event)
            % Habilitar funcionalidades App Principal
            app.AppVWBA.Enable_Project;  
            delete(app)            
            
        end

        % Value changed function: Check_UserTotal
        function Check_UserTotalValueChanged(app, event)
            value = app.Check_UserTotal.Value;
            
            if value
                app.VWB_UserTotal.Enable = "on";
            else
                app.VWB_UserTotal.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.App_Report = uifigure('Visible', 'off');
            app.App_Report.Name = 'MATLAB App';
            app.App_Report.CloseRequestFcn = createCallbackFcn(app, @App_ReportCloseRequest, true);
            app.Table = uitable(app.App_Report);
            app.Table.ColumnName = {'Activity'; 'Indicator'; 'Result available'; 'Include in report'};
            app.Table.RowName = {};
            app.Table.ColumnEditable = [false false false true];
            app.Image = uiimage(app.App_Report);
            app.Image.ImageSource = 'Logo_N4W-Facility.png';
            app.Image2 = uiimage(app.App_Report);
            app.Image2.ImageSource = 'Logo_TNC.png';
            app.VolumetricWaterBenefitAccountingLabel = uilabel(app.App_Report);
            app.VolumetricWaterBenefitAccountingLabel.Text = {'Volumetric Water Benefit Accounting'; '& TNC Freshwater Metrics Estimation'};
            app.Button_WCN1 = uibutton(app.App_Report, 'push');
            app.Button_WCN1.ButtonPushedFcn = createCallbackFcn(app, @Button_WCN1Pushed, true);
            app.Button_WCN1.Icon = 'Icons_Report.png';
            app.Button_WCN1.Text = 'Create';
            app.SelecttheindicatorsthatwillbecontainedinthereportLabel = uilabel(app.App_Report);
            app.SelecttheindicatorsthatwillbecontainedinthereportLabel.Text = {'This module facilitates the creation of the "Volumetric Benefits Report". The report generated will be an HTML file that includes:'; 'watershed description, location, volumetric benefits, metrics for rivers, wetlands and people, and the percentage of areas important for '; 'biodiversity. To generate the report, please select the indicators you wish to include.'};
            app.StatusMetric = uicheckbox(app.App_Report);
            app.StatusMetric.ValueChangedFcn = createCallbackFcn(app, @StatusMetricValueChanged, true);
            app.StatusMetric.Text = 'The Nature Conservancy - Impact Metrics';
            app.PanelMetric = uipanel(app.App_Report);
            app.PanelMetric.Enable = 'off';
            app.TableMetrics = uitable(app.PanelMetric);
            app.TableMetrics.ColumnName = {'Metric'; 'Include in report'};
            app.TableMetrics.RowName = {};
            app.TableMetrics.ColumnEditable = [false true];
            app.TerrestrialinterventionareasEditFieldLabel = uilabel(app.PanelMetric);
            app.TerrestrialinterventionareasEditFieldLabel.Text = {'Freshwater metrics are estimated using the global databases recommended by the Freshwater Metrics Guidance for 2030 Goals. To '; ''; 'estimate metrics, please provide the and per capita water consumption (l/day): '};
            app.Dota = uieditfield(app.PanelMetric, 'numeric');
            app.Dota.Limits = [0 Inf];
            app.Dota.Value = 150;
            app.VWB_UserTotal = uieditfield(app.PanelMetric, 'numeric');
            app.VWB_UserTotal.Limits = [0 Inf];
            app.VWB_UserTotal.Enable = 'off';
            app.Check_UserTotal = uicheckbox(app.PanelMetric);
            app.Check_UserTotal.ValueChangedFcn = createCallbackFcn(app, @Check_UserTotalValueChanged, true);
            app.Check_UserTotal.Text = 'Enter the volume of water with which you want to estimate the metric (m3/year):';
            app.StatusBio = uicheckbox(app.App_Report);
            app.StatusBio.Text = 'Areas of biodiversity importance';
            app.StatusCO2 = uicheckbox(app.App_Report);
            app.StatusCO2.Text = 'Total carbon equivalent storage';
            % Show the figure after all components are created
            app.App_Report.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_4_Report(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.App_Report)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.App_Report)
        end
    end
end